classdef STREEG < matlab.apps.AppBase
    %STREEG
    %   Bu uygulama EEG Sinyallerini izlemek amacıyla gerçekleştirilmiştir.

    % Properties that correspond to app components
    properties (Access = public)
        EEGUIFigure                     matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        Label2                          matlab.ui.control.Label
        STREEGLabel                     matlab.ui.control.Label
        Labelx                          matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        LineFilterDesignTab             matlab.ui.container.Tab
        ShowSNRCheckBox                 matlab.ui.control.CheckBox
        ChannelNameLabel                matlab.ui.control.Label
        Channel_NoEditField             matlab.ui.control.NumericEditField
        Channel_NoEditFieldLabel        matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ShowOnlySelectedFrequencyCheckBox  matlab.ui.control.CheckBox
        EEG_BandsButtonGroup            matlab.ui.container.ButtonGroup
        UserDefinedButton               matlab.ui.control.RadioButton
        GamaButton                      matlab.ui.control.RadioButton
        BetaButton                      matlab.ui.control.RadioButton
        AlphaButton                     matlab.ui.control.RadioButton
        ThetaButton                     matlab.ui.control.RadioButton
        DeltaButton                     matlab.ui.control.RadioButton
        SaveChannelFilterOutCheckBox    matlab.ui.control.CheckBox
        NotchFrequencyEditField         matlab.ui.control.NumericEditField
        NotchFrequencyLabel             matlab.ui.control.Label
        LineFilterButton                matlab.ui.control.Button
        F2StopFrequecyEditField         matlab.ui.control.NumericEditField
        F2StopFrequecyEditFieldLabel    matlab.ui.control.Label
        F2PassFrequecyEditField         matlab.ui.control.NumericEditField
        F2PassFrequecyEditFieldLabel    matlab.ui.control.Label
        BPPassGainEditField             matlab.ui.control.NumericEditField
        BPPassGainEditFieldLabel        matlab.ui.control.Label
        BPStopGainEditField             matlab.ui.control.NumericEditField
        BPStopGainEditFieldLabel        matlab.ui.control.Label
        HPPassGainEditField             matlab.ui.control.NumericEditField
        HPPassGainEditFieldLabel        matlab.ui.control.Label
        HPStopGainEditField             matlab.ui.control.NumericEditField
        HPStopGainEditFieldLabel        matlab.ui.control.Label
        PassbandFrequencyEditField      matlab.ui.control.NumericEditField
        PassbandFrequencyEditFieldLabel  matlab.ui.control.Label
        F1PassFrequecyEditField         matlab.ui.control.NumericEditField
        F1PassFrequecyEditFieldLabel    matlab.ui.control.Label
        F1StopFrequecyEditField         matlab.ui.control.NumericEditField
        F1StopFrequecyEditFieldLabel    matlab.ui.control.Label
        StopBandFrequencyEditField      matlab.ui.control.NumericEditField
        StopBandFrequencyEditFieldLabel  matlab.ui.control.Label
        BandPassCheckBox                matlab.ui.control.CheckBox
        HighPassCheckBox                matlab.ui.control.CheckBox
        NotchCheckBox                   matlab.ui.control.CheckBox
        Notch_Q_factorEditField         matlab.ui.control.NumericEditField
        Notch_Q_factorEditFieldLabel    matlab.ui.control.Label
        ExperimentTab                   matlab.ui.container.Tab
        Line_Filter_PerformanceCheckBox  matlab.ui.control.CheckBox
        Artifact_Filter_PerformanceCheckBox  matlab.ui.control.CheckBox
        LineFilter_EDFCheckBox          matlab.ui.control.CheckBox
        PSpectrumCheckBox               matlab.ui.control.CheckBox
        ArtifactFilter_EDFCheckBox      matlab.ui.control.CheckBox
        Save_Channel_MatCheckBox        matlab.ui.control.CheckBox
        SaveMatCheckBox                 matlab.ui.control.CheckBox
        SaveFiltredEEGCheckBox          matlab.ui.control.CheckBox
        Topographic_mapCheckBox         matlab.ui.control.CheckBox
        ScalogramCWTCheckBox            matlab.ui.control.CheckBox
        SpectrogramCheckBox             matlab.ui.control.CheckBox
        RUNButton                       matlab.ui.control.Button
        SelectPatientCheckBox           matlab.ui.control.CheckBox
        EEGParametreleriPanel           matlab.ui.container.Panel
        Movement_NumberEditField        matlab.ui.control.NumericEditField
        Movement_NumberEditFieldLabel   matlab.ui.control.Label
        Total_ExperimentEditField       matlab.ui.control.NumericEditField
        Total_ExperimentEditFieldLabel  matlab.ui.control.Label
        Experiment_DurationEditField    matlab.ui.control.NumericEditField
        Experiment_DurationEditFieldLabel  matlab.ui.control.Label
        Idle_TimeEditField              matlab.ui.control.NumericEditField
        Idle_TimeEditFieldLabel         matlab.ui.control.Label
        F_SamplingLabel                 matlab.ui.control.Label
        SamplingFrequencyHzLabel        matlab.ui.control.Label
        F_PatientNameLabel              matlab.ui.control.Label
        PatientNameLabel                matlab.ui.control.Label
        F_TimeLabel                     matlab.ui.control.Label
        F_RecordLabel                   matlab.ui.control.Label
        TotalRecordTimesecLabel         matlab.ui.control.Label
        TotalRecordLabel                matlab.ui.control.Label
        Inspect_FileTab                 matlab.ui.container.Tab
        milisecondLabel                 matlab.ui.control.Label
        speedEditField                  matlab.ui.control.NumericEditField
        speedEditFieldLabel             matlab.ui.control.Label
        ShowChannel_FrquencPowerCheckBox  matlab.ui.control.CheckBox
        SpeedSlider                     matlab.ui.control.Slider
        SpeedSliderLabel                matlab.ui.control.Label
        MakeVideoCheckBox               matlab.ui.control.CheckBox
        LineFilterCheckBox              matlab.ui.control.CheckBox
        ExperimentNoListBox             matlab.ui.control.ListBox
        ExperimentNoListBoxLabel        matlab.ui.control.Label
        Show_Only_Selected_ChannelsCheckBox  matlab.ui.control.CheckBox
        ArtifacFilterCheckBox           matlab.ui.control.CheckBox
        SaveRawEEGCheckBox              matlab.ui.control.CheckBox
        Inspect_MATButton               matlab.ui.control.Button
        Inspect_EDFButton               matlab.ui.control.Button
        ParameterTab                    matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        WaveletTypeListBox              matlab.ui.control.ListBox
        WaveletTypeListBoxLabel         matlab.ui.control.Label
        ExperimentNameEditField         matlab.ui.control.EditField
        ExperimentNameEditFieldLabel    matlab.ui.control.Label
        ColorLabel                      matlab.ui.control.Label
        ColorListBox                    matlab.ui.control.ListBox
        SpectrogramFilterTypeListBox    matlab.ui.control.ListBox
        SpectrogramFilterTypeListBoxLabel  matlab.ui.control.Label
        Windows_SizeEditField           matlab.ui.control.NumericEditField
        Windows_SizeEditFieldLabel      matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        ElectrodesTab                   matlab.ui.container.Tab
        ArtifactFilterReferenceListBox  matlab.ui.control.ListBox
        ArtifactFilterReferenceListBoxLabel  matlab.ui.control.Label
        FPZCheckBox                     matlab.ui.control.CheckBox
        T2CheckBox                      matlab.ui.control.CheckBox
        T1CheckBox                      matlab.ui.control.CheckBox
        OZCheckBox                      matlab.ui.control.CheckBox
        O2CheckBox                      matlab.ui.control.CheckBox
        O1CheckBox                      matlab.ui.control.CheckBox
        T6CheckBox                      matlab.ui.control.CheckBox
        T5CheckBox                      matlab.ui.control.CheckBox
        P3CheckBox                      matlab.ui.control.CheckBox
        P4CheckBox                      matlab.ui.control.CheckBox
        PZCheckBox                      matlab.ui.control.CheckBox
        A1CheckBox                      matlab.ui.control.CheckBox
        T3CheckBox                      matlab.ui.control.CheckBox
        A2CheckBox                      matlab.ui.control.CheckBox
        T4CheckBox                      matlab.ui.control.CheckBox
        FP2CheckBox                     matlab.ui.control.CheckBox
        Image                           matlab.ui.control.Image
        C4CheckBox                      matlab.ui.control.CheckBox
        FP1CheckBox                     matlab.ui.control.CheckBox
        FZCheckBox                      matlab.ui.control.CheckBox
        F8CheckBox                      matlab.ui.control.CheckBox
        F7CheckBox                      matlab.ui.control.CheckBox
        F4CheckBox                      matlab.ui.control.CheckBox
        F3CheckBox                      matlab.ui.control.CheckBox
        CZCheckBox                      matlab.ui.control.CheckBox
        C3CheckBox                      matlab.ui.control.CheckBox
        HelpTab                         matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        supportTab                      matlab.ui.container.Tab
        PleasereportanycommentsorerrorsLabel  matlab.ui.control.Label
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
    end

    properties (Access = private)
           
    ChanCB          % Checkbox handle'ları
    ChanXY          % Kanal koordinatları (Nx2)
    ChanMarker      % Kanal noktaları
    SelRing         % Seçili kanal halkası % Description
    end
    
    properties (Access = public)
     end
    
    methods (Access = public)
            end


    methods (Access = private)

        function [x, y] = do_25ch_1020_coords(~,labels)
            allLabels = {'Fp1','Fp2','F3','F4','C3','C4','P3','P4','O1','O2', ...
                'F7','F8','T3','T4','T5','T6','Fz','Cz','Pz','Oz', ...
                'Fpz','A1','A2','T1','T2'};
            allX = [-0.45, 0.45, ...   % Fp1, Fp2
                -0.45, 0.45, ...   % F3, F4
                -0.50, 0.50, ...   % C3, C4
                -0.45, 0.45, ...   % P3, P4
                -0.50, 0.50, ...   % O1, O2
                -0.85, 0.85, ...   % F7, F8
                -0.90, 0.90, ...   % T3, T4
                -0.85, 0.85, ...   % T5, T6
                0.00, 0.00, ...   % Fz, Cz
                0.00, 0.00, ...   % Pz, Oz
                0.00, ...         % Fp
                -1.00, 1.00, ...   % A1, A2
                -0.90, 0.90];      % T1, T2

            allY = [ 0.90, 0.90, ...   % Fp1, Fp2
                0.60, 0.60, ...   % F3, F4
                0.00, 0.00, ...   % C3, C4
                -0.60,-0.60, ...   % P3, P4
                -0.90,-0.90, ...   % O1, O2
                0.60, 0.60, ...   % F7, F8
                0.00, 0.00, ...   % T3, T4
                -0.60,-0.60, ...   % T5, T6
                0.70, 0.00, ...   % Fz, Cz
                -0.70,-0.95, ...   % Pz, Oz
                1.00, ...         % Fpz
                0.00, 0.00, ...   % A1, A2
                0.30, 0.30];      % T1, T2

            x = zeros(numel(labels),1);
            y = zeros(numel(labels),1);

            for i = 1:numel(labels)
                idx = find(strcmpi(allLabels, labels{i}));
                if isempty(idx)
                    error('Unknown Channel Name: %s', labels{i});
                end
                x(i) = allX(idx);
                y(i) = allY(idx);
            end
        end

        function doCreatevideo(app,ToplamEEG,SecilenEEG, chanX, chanY,ToplamEEGKanalAdi,SecilenKanalAdi,fo, DosyaAdi,aaa,Sure,dtime, deneySure)
            % SCROLLING EEG + RAW/CLEAN SCALP ANİMASYONU (20 FPS, 1280x720)
            vw = VideoWriter(DosyaAdi, 'MPEG-4');
            vw.FrameRate = 20;                       % 20 FPS
            open(vw);
            [~, Nc1] = size(ToplamEEG);
           %  t = (0:Ns-1)/fo;

            [Ns, Nc2] = size(SecilenEEG);

            winSec     = deneySure;  %  7;                          % scrolling pencere (s)
            winSamples = round(winSec * fo);
            offset     = 150;                        % kanal offset
            fig = figure('Name',DosyaAdi,'Position',[100 100 1280 720]);   % 1280x720

            step=round((fo/10)*app.SpeedSlider.Value);


            for i = 1:step:Ns    % her 10. örnekten frame (isteğe göre 1,5,10)
                      
                clf;
 
                subplot(2,2,[1 3]); % SCROLLING EEG
                i2 = i;
                i1 = max(1, i2 - winSamples);
                hold on;
                for ch = 1:Nc2
                    plot(dtime(i1:i2),SecilenEEG(i1:i2,ch) + (ch-1)*offset, 'k');
                    text(dtime(i1), (ch-1)*offset, SecilenKanalAdi{ch}, 'Color','b','FontSize',8);
                end
                hold off;
                ylim([-(offset) (Nc2)*offset]);    % ylim([-(offset) (KanalAdedi)*offset])
                title(sprintf(DosyaAdi,'  SCROLLING EEG  t = %.2f s', dtime(i2)), 'FontSize', 12);
                xlabel('Time (sec)');
                ylabel('Channels');
                grid on;
                colors = lines(size(Sure,1));
                ax = gca;
                yLimits = ylim;
                t1 = dtime(i1);
                t2 = dtime(i2);
                draw_event_patches_scrolling(app,ax, t1, t2, yLimits, Sure, aaa, colors);
                set(get(ax,'YAxis'),'Visible','off');

                if dtime(i2) > dtime(i1)  % x-limit için güvenli kontrol (hata önleme)
                    xlim([dtime(i1) dtime(i2)]);
                else
                    xlim([dtime(i2)-0.01 dtime(i2)+0.01]);
                end

                subplot(2,2,2); % SAĞ ALT: CLEAN SCALP
                doSimple_topoplot(app,ToplamEEG(i2,:).', chanX, chanY, ToplamEEGKanalAdi);
                 title(sprintf(' Scalp — t=%.2f s', dtime(i2)), 'FontSize', 12);
                
                subplot(2,2,4);
                hold on;
                for ch = 1:Nc1
                    plot(dtime,ToplamEEG(:,ch)+ (ch-1)*1200);
                end
                set(gca, 'YTick', []);  

                hold off;

                frame = getframe(fig);
                writeVideo(vw, frame);
            end
           close(vw);
        end


        function draw_event_patches_scrolling(~, ax, t1, t2, yLimits, Sure, aaa, colors)
            hold(ax,'on');
            for i = 1:size(Sure,1)
                x1 = Sure(i,1);
                x2 = Sure(i,2);

                if x2 < t1 || x1 > t2   % pencere ile kesişmiyorsa geç
                    continue;
                end
                xs = max(x1, t1);  % pencere içine kırp
                xe = min(x2, t2);

                % patch ve text (ortalanmış, döndürülmüş)
                patch(ax, [xs xe xe xs], [yLimits(1) yLimits(1) yLimits(2) yLimits(2)],colors(i,:),'FaceAlpha',0.2,'EdgeColor','none');
                text(ax,(xs+xe)/2, mean(yLimits),aaa{i},'Rotation',90,'HorizontalAlignment','center','FontSize',12,'Color','red');
            end
            hold(ax,'off');
        end

        % Topoplot çiz
        function doSimple_topoplot(~,values, chanX, chanY, chanLabels)
            values = values(:);
            if numel(values) ~= numel(chanX)
                error('values ile kanal sayısı uyuşmuyor.');
            end
            gridRes = 140;
            xr = linspace(-1,1,gridRes);
            yr = linspace(-1,1,gridRes);
            [Xg,Yg] = meshgrid(xr,yr);
            mask = (Xg.^2 + Yg.^2 <= 1);

            F = scatteredInterpolant(chanX, chanY, values, 'natural','nearest');
            Z = F(Xg,Yg);
            Z(~mask) = NaN;

            contourf(Xg,Yg,Z,20,'LineColor','none'); hold on;
            axis equal off;
            colormap jet; colorbar;

            th = linspace(0,2*pi,200);
            plot(cos(th), sin(th),'k','LineWidth',2);

            plot(chanX, chanY,'k.','MarkerSize',10);
            for k = 1:numel(chanLabels)
                text(chanX(k)+0.03, chanY(k), chanLabels{k},'FontSize',8,'Color','k');
            end
        end

        % Draw her kanala ait fft bilgisi
        function doPlot_IC_panels(~,IC,chanLabels, fs,DosyaAdi,dtime) % IC’leri 2’lü panel (Time + PSD) olarak çizer

            [~, Nc] = size(IC);
            for k = 1:Nc
                figure('Name', sprintf(DosyaAdi,'%s – IC Panel', chanLabels{k}),'Position', [100 100 1200 450]);
                subplot(1,2,1);
                plot(dtime,IC(:,k),'k'); grid on;
                title(sprintf('%s – IC Time', chanLabels{k}));
                xlabel('Time(sec)'); ylabel('Amplitude(mV)');

                subplot(1,2,2);   % PSD
                [pxx,f] = pwelch(IC(:,k), fs, fs/2, fs, fs);
                plot(f,10*log10(pxx),'b','LineWidth',1.2); grid on;
                xlim([0 60]);
                xlabel('Hz'); ylabel('dB');
                title(sprintf('%s – IC PSD', chanLabels{k}));
            end
        end

        % Draw Raw Data
        function  doFilterDrawRaw(app) % filtre ciziyor
           [EEGraw, fo,ws, ov,SelectedFilterFileName]= doFilterOpen(app);
           EEGraw = EEGraw(app.Channel_NoEditField.Value,:);
            Ttime=(0:length(EEGraw)-1)/fo;
            dosyaadi=append(SelectedFilterFileName ,'Channel Number=',string(app.Channel_NoEditField.Value));
            fa=figure(); set(fa,'Name',dosyaadi) ;

            subplot(5,1,1);      % Raw time  data
            plot(Ttime, EEGraw) ;
            xlabel('Time(sec)');ylabel('mVolt');title('Raw signal time graph')
            if app.SaveChannelFilterOutCheckBox.Value== true
                deneyAdi=app.ExperimentNameEditField.Value;
                save(append(deneyAdi,'\FilterDatalar\','RAW',string(dosyaadi),'.mat'), "EEGraw");
            end

            subplot(5,1,2);  % raw frekans güç grafiği
            [p,f] = pspectrum(EEGraw,fo);
            plot(f,pow2db(p));
            grid on;
            title("Raw Signal Single-Sided Amplitude");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

            subplot(5,1,3); %raw spectrogram
            [~,numSamples] = size(EEGraw);
            nfft = 2^nextpow2(numSamples);
      	   f1=app.F1PassFrequecyEditField.Value;
           f2=app.F2PassFrequecyEditField.Value;

           [s, f, t] = spectrogram(EEGraw, hann(ws), ov, nfft, fo);
           val = 10*log10(abs(s));
           SpectrogramMatrix(:,:,1) = val; % 1 kanal numarası

           if app.ShowOnlySelectedFrequencyCheckBox.Value==true
               freqMask = (f >= f1) & (f <= f2);
               f_selected = f(freqMask);
               Selected_SpectrogramMatrix = SpectrogramMatrix(freqMask,:,:);
               imagesc(t,f_selected ,Selected_SpectrogramMatrix);
           else
               imagesc(t,f,SpectrogramMatrix);
           end

           set(gca,"yscale","linear","YDir","normal");
           xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('Raw Signal Spectrogram');colorbar;
            
            CwtFilter=app.WaveletTypeListBox.Value;   
           subplot(5,1,4);    %   Raw scalogram
           [cff, f]=cwt(EEGraw,fo,CwtFilter);
           val=10*log10(abs(cff));
           ScalogramMatrix(:,:,1) = val;

           if app.ShowOnlySelectedFrequencyCheckBox.Value==true
               freqMask = (f >= f1) & (f <= f2);
               fselected = f(freqMask);
               Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
               imagesc(Ttime,fselected,Selected_ScalogramMatrix);
           else
               imagesc(Ttime,f,ScalogramMatrix);
           end

           set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
           xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Raw Signal Scalogram');colorbar;

           subplot(5,1,5);   % Raw pspectrum

           [psc,f,t]= pspectrum(EEGraw,fo,"spectrogram"); % scoLogaram
           val = 10*log10(abs(psc));
           pspectrogramMatrix(:,:,1) = val;

           if app.ShowOnlySelectedFrequencyCheckBox.Value==true
               freqMask = (f >= f1) & (f <= f2);
               f_selected = f(freqMask);
               Selected_pspectrogramMatrix = pspectrogramMatrix(freqMask,:,:);
               imagesc(t,f_selected,Selected_pspectrogramMatrix);
           else
               imagesc(t,f,pspectrogramMatrix);
           end
           set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
           xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Raw Signal PowerSpectrom');colorbar;
        end


        %  İstenen EEG kanal adlarına göre EEG dosyasını okur
        function [EEG] = doEEGOku(~,dosya,EEGKanalNo,KanalAdedi,fktotal, fk,fo)
            EEG=zeros(KanalAdedi,fktotal);
            chn_no=1;
            if EEGKanalNo(1)==1  %  chn_no=1  C3 başlangıç kanal numarası

                for i=1:fk
                    a(i)=dosya.C3(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(2)==1  % chn_no=2  C4
                for i=1:fk
                    a(i)=dosya.C4(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(3)==1  %  chn_no=3 Cz
                for i=1:fk
                    a(i)=dosya.Cz(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(4)==1 %  chn_no=4  F3
                for i=1:fk
                    a(i)=dosya.F3(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(5)==1  %  chn_no=5  F4
                for i=1:fk
                    a(i)=dosya.F4(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(6)==1  %  chn_no=6  F7
                for i=1:fk
                    a(i)=dosya.F7(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(7)==1  %  chn_no=7 F8
                for i=1:fk
                    a(i)=dosya.F8(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(7,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(8)==1 %  chn_no=8  Fz
                for i=1:fk
                    a(i)=dosya.Fz(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(9)==1 % chn_no=9 Fp1
                for i=1:fk
                    a(i)=dosya.Fp1(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(10)==1 %  chn_no=10 Fp2
                for i=1:fk
                    a(i)=dosya.Fp2(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(11)==1  %  chn_no=11 Fpz
                for i=1:fk
                    a(i)=dosya.Fpz(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(12)==1  %  chn_no=12 A1
                for i=1:fk
                    a(i)=dosya.A1(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(13)==1  %  chn_no=13 A2
                for i=1:fk
                    a(i)=dosya.A2(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(14)==1  %  chn_no=14 O1
                for i=1:fk
                    a(i)=dosya.O1(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(15)==1  %  chn_no=15 O2
                for i=1:fk
                    a(i)=dosya.O2(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(16)==1  %  chn_no=16 Oz
                for i=1:fk
                    a(i)=dosya.Oz(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(17)==1  %  chn_no=17 P3
                for i=1:fk
                    a(i)=dosya.P3(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(18)==1  %  chn_no=18  P4
                for i=1:fk
                    a(i)=dosya.P4(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(19)==1  %  chn_no=19 T5
                for i=1:fk
                    a(i)=dosya.T5(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(20)==1  %  chn_no=20 T6
                for i=1:fk
                    a(i)=dosya.T6(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(21)==1  %  chn_no=21 Pz
                for i=1:fk
                    a(i)=dosya. Pz(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(22)==1  %  chn_no=22 T3
                for i=1:fk
                    a(i)=dosya. T3(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(23)==1  %  chn_no=23 T4
                for i=1:fk
                    a(i)=dosya. T4(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1   ;
            end

            if EEGKanalNo(24)==1  %  chn_no=24 T1
                for i=1:fk
                    a(i)=dosya. T1(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
                chn_no=chn_no+1;
            end

            if EEGKanalNo(25)==1    %  chn_no=25 T2
                for i=1:fk
                    a(i)=dosya. T2(i,1);
                end
                b=cell2mat(a); b=b';
                k=0;
                for i=1: fk
                    for j=1:fo
                        k=k+1;
                        EEG(chn_no,k)=b(i,j);
                    end
                end
            end
        end

        % EXCEL edf dosyasını Excel dosyasındaki zaman değerlerine göre  Okur
            function [Sure,DeneyAdi] = doExcelDosyaOku(~, ExcelSure, ExcelTxt, GorDeneyNo, UygulamaSayisi)
            aaa=1;jj=1;ii=1;
            for de=4:(3+GorDeneyNo)  % excel dosyasındaki deney sayıları
                for du=2:4:(UygulamaSayisi-1) % excel dosyasındaki uygulama sayıları
                    for ka=2:5 % excel sutn adresi
                        DeneyAdi(aaa,1)=strcat(string(ExcelTxt(1,2)), string(ExcelTxt(de,1)),string(ExcelTxt(2,du)), string(ExcelTxt(3,ka)));
                        % Sure(ii,jj)=int32(dominute2second(app,ExcelSure(ii,jj)));
                       Sure(ii,jj)=ExcelSure(ii,jj);
                        aaa=aaa+1;
                        if   jj <UygulamaSayisi
                            jj=jj+1;
                        else
                            jj=1;
                        end
                    end
                end
                ii=ii+1;
            end
        end

        function [SNRPath]=doSNRDirectory( ~,DizinAdi)
            SNRPath=append(DizinAdi,'\SNR\');
            if (~isfolder(SNRPath))   % Eğer dizin yoksa
                mkdir(SNRPath); % Yeni dizin oluştur
            end
        end

        % Spectrogram Dizini yapar
        function [SPyol,SPyolDusuk]=doSpectrumDirectory( ~,current_path , filterName,ws,ov,KanalAdedi,DeneySuresi)
           
                 DizinAdi=["DeepRightLegOpen","DeepRightLegDown","DeepRightLegUp","DeepRightLegClose",...
                "DeepRightArmOpen","DeepRightArmDown","DeepRightArmUp","DeepRightArmClose",...
                "DeepLeftLegOpen","DeepLeftLegDown","DeepLeftLegUp","DeepLeftLegClose",...
                "DeepLeftArmOpen","DeepLeftArmDown","DeepLeftArmUp","DeepLeftArmClose",...
                "MotorRightLegOpen","MotorRightLegDown","MotorRightLegUp","MotorRightLegClose",...
                "MotorRightArmOpen","MotorRightArmDown","MotorRightArmUp","MotorRightArmClose",...
                "MotorLeftLegOpen","MotorLeftLegDown","MotorLeftLegUp","MotorLeftLegClose",...
                "MotorLeftArmOpen","MotorLeftArmDown","MotorLeftArmUp","MotorLeftArmClose"];
            for i=1:32
                dd=DizinAdi(i);
                SPyol = append(current_path,'\Deney_Ws_',string(ws), '_' ,'Ov_',string(ov),'_' ,'Filtre_',string( filterName),'_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                SPyolDusuk=append(current_path,'\Dusuk_Ws_',string(ws), '_' ,'Ov_',string(ov),'_' ,'Filtre_',string( filterName),'_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                altdizin= append(SPyol,'\',dd);
                altdizinx=append(SPyolDusuk,'\',dd);
                
                if and(~isfolder(altdizin), ~isfolder(altdizinx))   % Eğer dizin yoksa
                    mkdir(altdizin); % Yeni dizin oluştur
                    mkdir(altdizinx);
                end
            end
        end

        %  PSpectrum Dizini  yapar
        function [SPCyol, SPCyolDusuk]=doPSpectrumDirectory( ~,current_path ,KanalAdedi,DeneySuresi)
            DizinAdi=["DeepRightLegOpen","DeepRightLegDown","DeepRightLegUp","DeepRightLegClose",...
                "DeepRightArmOpen","DeepRightArmDown","DeepRightArmUp","DeepRightArmClose",...
                "DeepLeftLegOpen","DeepLeftLegDown","DeepLeftLegUp","DeepLeftLegClose",...
                "DeepLeftArmOpen","DeepLeftArmDown","DeepLeftArmUp","DeepLeftArmClose",...
                "MotorRightLegOpen","MotorRightLegDown","MotorRightLegUp","MotorRightLegClose",...
                "MotorRightArmOpen","MotorRightArmDown","MotorRightArmUp","MotorRightArmClose",...
                "MotorLeftLegOpen","MotorLeftLegDown","MotorLeftLegUp","MotorLeftLegClose",...
                "MotorLeftArmOpen","MotorLeftArmDown","MotorLeftArmUp","MotorLeftArmClose"];

            for i=1:32
                dd=DizinAdi(i);
                SPCyol = append(current_path,'\PSpectrum', '_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                SPCyolDusuk=append(current_path,'\PSpectrumDusuk', '_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                
                altdizin= append(SPCyol,'\',dd);
                altdizinx=append(SPCyolDusuk,'\',dd);
                if and(~isfolder(altdizin), ~isfolder(altdizinx))   % Eğer dizin yoksa
                    mkdir(altdizin);
                    mkdir(altdizinx);
                end
            end
        end

        % Scalogram Dizini  yapar
        function [SCyol, SCyolDusuk]=doScalogramDirectory(~,current_path ,KanalAdedi,DeneySuresi)
           DizinAdi=["DeepRightLegOpen","DeepRightLegDown","DeepRightLegUp","DeepRightLegClose",...
                "DeepRightArmOpen","DeepRightArmDown","DeepRightArmUp","DeepRightArmClose",...
                "DeepLeftLegOpen","DeepLeftLegDown","DeepLeftLegUp","DeepLeftLegClose",...
                "DeepLeftArmOpen","DeepLeftArmDown","DeepLeftArmUp","DeepLeftArmClose",...
                "MotorRightLegOpen","MotorRightLegDown","MotorRightLegUp","MotorRightLegClose",...
                "MotorRightArmOpen","MotorRightArmDown","MotorRightArmUp","MotorRightArmClose",...
                "MotorLeftLegOpen","MotorLeftLegDown","MotorLeftLegUp","MotorLeftLegClose",...
                "MotorLeftArmOpen","MotorLeftArmDown","MotorLeftArmUp","MotorLeftArmClose"];

            for i=1:32
                dd=DizinAdi(i);
                SCyol = append(current_path,'\Scalogram', '_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                SCyolDusuk=append(current_path,'\ScalogramDusuk', '_' ,'Kanal_',string(KanalAdedi),'_' ,'Sure_',string(DeneySuresi),'\') ;
                altdizin= append(SCyol,'\',dd);
                altdizinx=append(SCyolDusuk,'\',dd);
                if and(~isfolder(altdizin), ~isfolder(altdizinx))   % Eğer dizin yoksa
                    mkdir(altdizin);
                    mkdir(altdizinx);
                end
            end
        end

        % Filtre için istenen dosyayı seç
        function  [EEGraw, fo,ws, ov, SelectedFilterFileName, FilterfullPath,ToplamKanalAdi ]= doFilterOpen(app)
            [SelectedFilterFileName, path] = uigetfile({'*.edf', 'edf (*.edf'}, 'Bir .edf dosyası seçin');
            if isequal(SelectedFilterFileName, 0)
                disp('Dosya seçilmedi.');
                EEGraw=0; fo=0; ws=0; ov=0;
            else
                FilterfullPath = fullfile(path, SelectedFilterFileName);
                dosya=edfread( FilterfullPath);
                info = edfinfo( FilterfullPath);
                fs=info.NumSamples(1,1);                   %  Bir satırdaki kayıt miktarı,
                sr=dosya.Properties.SampleRate;       % her bir saniye için örnekleme sayısı
                fo=sr*fs;                                                 % örnekleme frekansı (Hz) fo=sr*fs
                fk=info.NumDataRecords;                  % Toplam satır sayısı
                fktotal=fk*fo;                                         % Toplam kayıt adedi (adet)
                %ftotal=fk/sr;                                           % Toplam kayıt süresi (saniye)
                ToplamKanalAdi=["C3","C4","CZ","F3","F4","F7","F8","FZ","FP1","FP2","FPZ","A1","A2","O1","O2","OZ","P3","P4","T5","T6","PZ","T3","T4","T1","T2"];
                EEGKanalNo=[  1,   1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,  1,   1,  1,  1 ];
                KanalAdedi=25;
                ToplamEEG=doEEGOku(app,dosya,EEGKanalNo,KanalAdedi,fktotal,fk,fo);
               % kanalValue=app.Channel_NoEditField.Value;
                EEGraw=ToplamEEG;
                WindowSize=app.Windows_SizeEditField.Value;
                ov_size=app.OverlapEditField.Value;
                ws=WindowSize;
                ov=ov_size;
                % ws=fo*WindowSize;
                % ov=ws/ov_size;
            end
        end

        % Brain Map Dizini yapar
        function [BMyol,BMyolDusuk]=doBrainMapDirectory(~,current_path , DeneySuresi)
         
            DizinAdi=["DeepRightLegOpen","DeepRightLegDown","DeepRightLegUp","DeepRightLegClose",...
                "DeepRightArmOpen","DeepRightArmDown","DeepRightArmUp","DeepRightArmClose",...
                "DeepLeftLegOpen","DeepLeftLegDown","DeepLeftLegUp","DeepLeftLegClose",...
                "DeepLeftArmOpen","DeepLeftArmDown","DeepLeftArmUp","DeepLeftArmClose",...
                "MotorRightLegOpen","MotorRightLegDown","MotorRightLegUp","MotorRightLegClose",...
                "MotorRightArmOpen","MotorRightArmDown","MotorRightArmUp","MotorRightArmClose",...
                "MotorLeftLegOpen","MotorLeftLegDown","MotorLeftLegUp","MotorLeftLegClose",...
                "MotorLeftArmOpen","MotorLeftArmDown","MotorLeftArmUp","MotorLeftArmClose"];
            for i=1:32
                dd=DizinAdi(i);
                BMyol = append(current_path,'\TopoMap','_' ,'Sure_',string(DeneySuresi),'\') ;
                BMyolDusuk=append(current_path,'\TopoMapDusuk','_' ,'Sure_',string(DeneySuresi),'\') ;
                altdizin= append( BMyol,'\',dd);
                altdizinx=append( BMyolDusuk,'\',dd);
                if (~isfolder(altdizin))  % Eğer dizin yoksa
                    mkdir(altdizin);
                    mkdir(altdizinx);
                end
            end
        end

        % Notch Filter
        function  doFilterDrawNotch(app)
            app.Labelx.Text='Notch Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~ ,ToplamKanalAdi]= doFilterOpen(app);
            Fa=app.NotchFrequencyEditField.Value ; %  50 hz band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
           Wo = Fa/(fo/2);
           BW = Wo/Q;
           [b,a] = iirnotch(Wo,BW);
           EEGNotch  = filtfilt(b,a,EEGraw')';
                     
                 % filtre karakteristiğini çizer
            freqz(b,a,[],fo)
            
            if app.ShowSNRCheckBox.Value==true
             [N,~]=size(EEGraw);
            SNR_ch=zeros(N,1);
                for i=1:N
                    noise = EEGraw(i,:) - EEGNotch(i,:);
                    Ps = sum(EEGNotch(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                 fa=figure(); set(fa,'Name',SelectedFilterFileName) ; 
                 bar(SNR_ch);
                set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch Filter EEG Channel SNR Values');xtickangle(90);
            end

            EEGNotch= EEGNotch(app.Channel_NoEditField.Value,:); % sadece istene kanala ait 
            dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));
           Ttime=(0:length(EEGNotch)-1)/fo;

            if app.SaveChannelFilterOutCheckBox.Value== true
                deneyAdi=app.ExperimentNameEditField.Value;
                save(append(deneyAdi,'\FilterDatalar\','NOTCH',string(dosyaadi),'.mat'), "EEGNotch");
            end

            fa=figure(); set(fa,'Name',dosyaadi) ;   %  Notch time  data
            subplot(5,1,1)
            plot(Ttime, EEGNotch ) ; xlabel('Time(sec)');ylabel('mVolt');title('Notch Filter time signal')

            subplot(5,1,2)
            [pxx,f] = pspectrum(EEGNotch,fo);
            plot(f,pow2db(pxx));
            grid on;
            title("Single-Sided Amplitude Spectrum of F(t)");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

            f1=app.F1PassFrequecyEditField.Value;
            f2=app.F2PassFrequecyEditField.Value;

            subplot(5,1,3) ;        %% Notch Spectrogram
            nfft = 2^nextpow2(length(EEGNotch));
            [s, f, t]=spectrogram(EEGNotch,hann(ws),ov,nfft,fo);
            val = 10*log10(abs(s));
            SpectrogramMatrix(:,:,1) = val; % 1 kanal numarası
            if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                freqMask = (f >= f1) & (f <= f2);
                f_selected = f(freqMask);
                Selected_SpectrogramMatrix = SpectrogramMatrix(freqMask,:,:);
                imagesc(t,f_selected ,Selected_SpectrogramMatrix);
            else
                imagesc(t,f,SpectrogramMatrix);
            end
            colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
            xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('Notch Spectrogram');colorbar;

            CwtFilter=app.WaveletTypeListBox.Value;
            subplot(5,1,4);  % Notch Scolagaram
            [cff, f]=cwt(EEGNotch,fo,CwtFilter);
            val=10*log10(abs(cff));
            ScalogramMatrix(:,:,1) = val;

            if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                freqMask = (f >= f1) & (f <= f2);
                fselected = f(freqMask);
                Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                imagesc(Ttime,fselected,Selected_ScalogramMatrix);
            else
                imagesc(Ttime,f,ScalogramMatrix)
            end

            set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
            xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch Scalogram');colorbar;

            subplot(5,1,5);    % Raw pspectrum
            [psc,f,t]= pspectrum(EEGNotch,fo,"spectrogram");
            val = 10*log10(abs(psc));
            pspectrogramMatrix(:,:,1) = val;

            if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                freqMask = (f >= f1) & (f <= f2);
                f_selected = f(freqMask);
                Selected_pspectrogramMatrix = pspectrogramMatrix(freqMask,:,:);
                imagesc(t,f_selected ,Selected_pspectrogramMatrix);
            else
                imagesc(t,f,pspectrogramMatrix);
            end
            set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
            xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch Power Spectrum');colorbar;
        end

        function  EEGLineFiltered=doFilterNotch(app,fo,EEGraw)
            Fa=app.NotchFrequencyEditField.Value ; %  50 hz band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
            EEGLineFiltered  = filtfilt(b,a,EEGraw')';
        end

        % Band Pass Filter
        function  doFilterDrawBandPass(app)
            app.Labelx.Text='Band Pass Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~,ToplamKanalAdi]= doFilterOpen(app);
            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)
            Fs=fo;
            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
           EEGBPass = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGraw')';
            
           [N,~]=size( EEGBPass);        
            if app.ShowSNRCheckBox.Value==true

            SNR_ch=zeros(N,1);
                for i=1:N
                    noise = EEGraw(i,:) -  EEGBPass(i,:);
                    Ps = sum( EEGBPass(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
                bar(SNR_ch);
                set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Band Pass Filter EEG SNR Values');xtickangle(90);
            end
            EEGBPass= EEGBPass(app.Channel_NoEditField.Value,:);
            Ttime=(0:length(EEGBPass)-1)/fo;
          
                fvtool(Hd,Fs)  ;
                dosyaadi=append(SelectedFilterFileName ,'  Channel Number=',string(app.Channel_NoEditField.Value));
                if app.SaveChannelFilterOutCheckBox.Value== true
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','BANDPASS',string(dosyaadi),'.mat'), "EEGBPass");
                end

                fa=figure(); set(fa,'Name',dosyaadi) ;
                subplot(5,1,1)
                plot(Ttime,EEGBPass); xlabel('Time(sec)');ylabel('mVolt');title('Band Pass Filter time signal')

                subplot(5,1,2)
                [pxx,f] = pspectrum(EEGBPass,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of Band Pass Filter");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

                subplot(5,1,3) ;        %HP Spectrogram
                nfft = 2^nextpow2(length( EEGBPass));
                [s, f, t]=spectrogram(EEGBPass ,hann(ws),ov,nfft,fo);
                val = 10*log10(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end
                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('High Pass Spectrogram');colorbar;

                subplot(5,1,4);  %% HPScolagaram
                CwtFilter=app.WaveletTypeListBox.Value;   
                [cff, f]=cwt(EEGBPass,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Band Pass  Scalogram');colorbar;

                subplot(5,1,5);
                [psc,fsc,tsc]= pspectrum(EEGBPass,fo,"spectrogram");
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,psc);
                end

                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Power Spectrum');colorbar;
          
        end

        function EEGLineFiltered=doFilterBandPass(app,fo,EEGraw)
            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)
            Fs=fo;
            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
            EEGLineFiltered = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGraw')';
        end

        % High Pass Filter
        function doFilterDrawHighPass(app)
            app.Labelx.Text='High Pass Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~,ToplamKanalAdi]= doFilterOpen(app);

            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
            Fs=fo;       
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGHPass = filtfilt(Hd.Numerator,1,EEGraw')';
          
            if app.ShowSNRCheckBox.Value==true
           [N,~]=size(EEGHPass);
           SNR_ch=zeros(N,1);
               for i=1:N
                   noise = EEGraw(i,:) - EEGHPass(i,:);
                   Ps = sum(EEGHPass(i,:).^2);
                   Pn = sum(noise.^2);
                   SNR_ch(i,1) = 10 * log10(Ps / Pn);
               end
               fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
               bar(SNR_ch);
               set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
               xlabel('EEG Channels'); ylabel('SNR (dB)');title('High Pass Filter EEG SNR Values');xtickangle(90);
           end
           EEGHPass= EEGHPass(app.Channel_NoEditField.Value,:); % sadece istene kanala ait 
		   Ttime=(0:length(EEGHPass)-1)/fo;
       
                freqz(Hd,[],fo)
                dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));

                if app.SaveChannelFilterOutCheckBox.Value== true
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','HIGHPASS',string(dosyaadi),'.mat'), "EEGHPass");
                end

                fa=figure(); set(fa,'Name',dosyaadi) ;

                subplot(5,1,1)
                plot(Ttime,EEGHPass) ;
                xlabel('Time(sec)');ylabel('mVolt');title('High Pass Filter time signal')

                subplot(5,1,2)
                [pxx,f] = pspectrum( EEGHPass,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of High Pass Filter");xlabel("f (Hz)");ylabel("|P(dB)|");ylabel("'Power/Frequency (dB/Hz)")
                subplot(5,1,3) ;        % HP Spectrogram
                nfft = 2^nextpow2(length(EEGHPass));
                [s, f, t]=spectrogram(EEGHPass ,hann(ws),ov,nfft,fo);
                val = 10*log10(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end
                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('High Pass Spectrogram');colorbar;
                CwtFilter=app.WaveletTypeListBox.Value;  
                subplot(5,1,4);  %% HPScolagaram
                [cff, f]=cwt(EEGHPass,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('High Pass Scalogram');colorbar;

                subplot(5,1,5);
                [psc,fsc,tsc]= pspectrum(EEGHPass ,fo,"spectrogram");
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,psc);
                end

                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('High Pass  Power Spectrum');colorbar;
           
        end

        function EEGLineFiltered=doFilterHighPass(app,fo, EEGraw)
            Fs=fo;
            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)

            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGLineFiltered = filtfilt(Hd.Numerator,1,EEGraw')';
	   end

        % Notch and HighPass Filter
        function doFilterDrawNotchandHP(app)
            app.Labelx.Text='Notch and High Pass Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~, ToplamKanalAdi]= doFilterOpen(app);
            Fs=fo;
            Fa=app.NotchFrequencyEditField.Value ;     % band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
% Notch
             EEGNotch  = filtfilt(b,a,EEGraw');

            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
% HP
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass, Fs);
            Hd = design(h, 'equiripple', 'MinOrder', 'any', 'StopbandShape', 'flat');
            EEGHNotchandPass = filtfilt(Hd.Numerator,1,EEGNotch)';
                       
            if app.ShowSNRCheckBox.Value==true
            [N,~]=size(EEGHNotchandPass);
            SNR_ch=zeros(N,1);
                for i=1:N
                    noise = EEGraw(i,:) - EEGHNotchandPass(i,:);
                    Ps = sum(EEGHNotchandPass(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
                bar(SNR_ch);
                set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch & Band Pass Filter EEG SNR Values');xtickangle(90);
            end
             EEGHNotchandPass= EEGHNotchandPass(app.Channel_NoEditField.Value,:); % sadece istene kanala ait 
			 Ttime=(0:length(EEGHNotchandPass)-1)/fo;
                dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));

                if app.SaveChannelFilterOutCheckBox.Value== true
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','HIGHandNOTCH',string(dosyaadi),'.mat'), "EEGHNotchandPass");
                end

                fa=figure(); set(fa,'Name',dosyaadi) ;
                subplot(5,1,1)
                plot(Ttime,    EEGHNotchandPass) ;
                xlabel('Time(sec)');ylabel('mVolt');title('Notch & High Pass Filter time signal')

                subplot(5,1,2)
                [pxx,f] = pspectrum(EEGHNotchandPass,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of Notch & High Pass Filter");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

                subplot(5,1,3) ;        %% HP Spectrogram
                nfft = 2^nextpow2(length(EEGHNotchandPass));
                [s,f,t]=spectrogram(EEGHNotchandPass ,hann(ws),ov,nfft,fo);
                val =10* log(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end

                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('Notch & High Pass Spectrogram');colorbar;
                CwtFilter=app.WaveletTypeListBox.Value;     
                subplot(5,1,4);  % HPScolagaram
                [cff, f]=cwt(EEGHNotchandPass,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch & High Pass  Scalogram');colorbar;

                subplot(5,1,5);
                [psc,fsc,tsc]= pspectrum(EEGHNotchandPass,fo,"spectrogram");

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,psc);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch & High Pass  Power Spectrum');colorbar;
            end

        function EEGLineFiltered=doFilterNotchandHP(app,fo, EEGraw)
            Fa=app.NotchFrequencyEditField.Value ;     % band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
            EEGnotch = filtfilt(b,a,EEGraw');  % notch filtre
            Fs=fo;
            Fstop =app.StopBandFrequencyEditField.Value;   % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value;    % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60 Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1     Passband Ripple (dB)

            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGLineFiltered = filtfilt(Hd.Numerator,1,EEGNotch)';
        end

        % Notch and Band Pass Filter
        function  doFilterDrawNotchandBP(app)
            app.Labelx.Text='Notch and Band Pass Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~,ToplamKanalAdi]= doFilterOpen(app);
            Fa=app.NotchFrequencyEditField.Value ;     % band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
% Notch
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
% BP   
            EEGNotch  = filtfilt(b,a,EEGraw');
            Fs=fo;
            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)

            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop,Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
            EEGNotchandBPass = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGNotch)';
       
            if app.ShowSNRCheckBox.Value==true
                [N,~]=size( EEGNotchandBPass);
                SNR_ch=zeros(N,1);
                for i=1:N
                    noise = EEGraw(i,:) - EEGNotchandBPass(i,:);
                    Ps = sum(EEGNotchandBPass(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                    fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
                    bar(SNR_ch);
                    set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                    xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch & Band Pass Filter EEG SNR Values');xtickangle(90);
            end

            EEGNotchandBPass= EEGNotchandBPass(app.Channel_NoEditField.Value,:); % sadece istene kanala ait 
			Ttime=(0:length(EEGNotchandBPass)-1)/fo;
         
            dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));

                if app.SaveChannelFilterOutCheckBox.Value== true
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','BANDandNOTCH',string(dosyaadi),'.mat'), "EEGNotchandBPass");
                end

                fa=figure(); set(fa,'Name',dosyaadi) ;
                subplot(5,1,1)
                plot(Ttime, EEGNotchandBPass ) ;
                xlabel('Time(sec)');ylabel('mVolt');title('Notch & Band Pass Filter time signal')

                subplot(5,1,2)
                FFTEEGBPass= fft(EEGNotchandBPass );   %  FFT
                [pxx,f] = pspectrum(EEGNotchandBPass,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of Notch & Band Pass Filter");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

                subplot(5,1,3) ;        % HP Spectrogram
                nfft = 2^nextpow2(length( FFTEEGBPass));
                [s, f, t]=spectrogram(EEGNotchandBPass  ,hann(ws),ov,nfft,fo);
                val = 10*log10(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end
                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('Notch & High Pass Spectrogram');colorbar;
                CwtFilter=app.WaveletTypeListBox.Value;  
                subplot(5,1,4);  % HPScolagaram
                [cff, f]=cwt(EEGNotchandBPass,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch & High Pass  Scalogram');colorbar;

                subplot(5,1,5);
                [psc,fsc,tsc]= pspectrum(EEGNotchandBPass,fo,"spectrogram");
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,psc);
                end

                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch & High Pass Spectrum');colorbar;
           
        end

        function  EEGLineFiltered=doFilterNotchandBP(app,fo , EEGraw)
            Fa=app.NotchFrequencyEditField.Value ;     % band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
            EEGNotch = filtfilt(b,a,EEGraw');  % notch filtre
            Fs=fo;     
            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)
            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
           EEGLineFiltered = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGNotch)';
        end

        % BP and HP Filter
        function doFilterDrawBPandHP(app)
            app.Labelx.Text='Band Pass and High Pass Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~,ToplamKanalAdi]= doFilterOpen(app);
           
% HP Filter
            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
            Fs=fo;
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGHPass = filtfilt(Hd.Numerator,1,EEGraw')';
 %BP
           F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)

            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
            EEGBPandHPass  = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGHPass')';
               
             [N,~]=size(EEGBPandHPass);
               SNR_ch=zeros(N,1);
           if app.ShowSNRCheckBox.Value==true
                for i=1:N
                    noise = EEGraw(i,:) - EEGBPandHPass(i,:);
                    Ps = sum(EEGBPandHPass(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
                bar(SNR_ch);
                set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Band & High Pass Filter EEG SNR Values');xtickangle(90);
            end
            EEGBPandHPass= EEGBPandHPass(app.Channel_NoEditField.Value,:); % sadece istenen kanala ait 
		   Ttime=(0:length(EEGBPandHPass )-1)/fo;
           dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));

                if app.SaveChannelFilterOutCheckBox.Value== true
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','BANDandHIGHPASS',string(dosyaadi),'.mat'), "EEGBPandHPass");
                end
                fa=figure(); set(fa,'Name',dosyaadi) ;

                subplot(5,1,1)
                plot(Ttime,    EEGBPandHPass) ;
                xlabel('Time(sec)');ylabel('mVolt');title('Band Pass & High Pass  Filter time signal')

                subplot(5,1,2)
                [pxx,f] = pspectrum(EEGBPandHPass,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of Band Pass & Hign Pass Filter");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")

                subplot(5,1,3) ;        %% HP Spectrogram
                nfft = 2^nextpow2(length( EEGBPandHPass));
                [s, f, t]=spectrogram(EEGBPandHPass ,hann(ws),ov,nfft,fo);
                val = 10*log10(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end
                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('High Pass & Band  Pass  Spectrogram');colorbar;

                subplot(5,1,4);  %% HPScolagaram
                CwtFilter=app.WaveletTypeListBox.Value;   
                [cff, f]=cwt(EEGBPandHPass,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('High Pass & Band Pass Scalogram');colorbar;

                subplot(5,1,5);

                [psc,fsc,tsc]= pspectrum(EEGBPandHPass,fo,"spectrogram");
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,sc);
                end
                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('High Pass & Band Pass Power Spectrum');colorbar;
                 end

        function EEGLineFiltered=doFilterBPandHP(app, fo , EEGraw)
            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
            Fs=fo;
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGHPass = filtfilt(Hd.Numerator,1,EEGraw')';

            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)

            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
            EEGLineFiltered  = filtfilt(Hd.sosMatrix, Hd.ScaleValues, EEGHPass')';
            EEGLineFiltered = filter(Hd,EEGHPass);
       end

        % Notch  & HP & BP Filtre
        function doFilterDrawALL(app)
            app.Labelx.Text='All Filter is prosessing Please WAIT';
            [EEGraw, fo,ws, ov, SelectedFilterFileName, ~,ToplamKanalAdi]= doFilterOpen(app);
            Fa=app.NotchFrequencyEditField.Value ; %  50 hz band durdurma frekansı 50 hz
   % Notch Filter
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
            EEGNotch  = filtfilt(b,a,EEGraw');
   % HP Filter
            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
            Fs=fo;  
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGNotchandHPass = filtfilt(Hd.Numerator,1,EEGNotch);
% BP Filter
            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)
            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
            EEGALL = filtfilt(Hd.sosMatrix, Hd.ScaleValues,EEGNotchandHPass)';
                 
            if app.ShowSNRCheckBox.Value==true
                    [N,~]=size(EEGALL);
                    SNR_ch=zeros(N,1);
                for i=1:N
                    noise = EEGraw(i,:) - EEGALL(i,:);
                    Ps = sum(EEGALL(i,:).^2);
                    Pn = sum(noise.^2);
                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                end
                fa=figure(); set(fa,'Name',SelectedFilterFileName) ;
                bar(SNR_ch);
                set(gca, 'XTick', 1:length(ToplamKanalAdi)); set(gca, 'XTickLabel', ToplamKanalAdi)
                xlabel('EEG Channels'); ylabel('SNR (dB)');title('High Pass & Band Pass & Notch Filter EEG SNR Values');xtickangle(90);
            end
            EEGALL= EEGALL(app.Channel_NoEditField.Value,:); % sadece istene kanala ait

           Ttime=(0:length(EEGALL)-1)/fo;
        
            dosyaadi=append(SelectedFilterFileName ,'  Channel Number=', string(app.Channel_NoEditField.Value));
                if app.SaveChannelFilterOutCheckBox.Value== true
                   deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','ALLFILTER',string(dosyaadi),'.mat'), "EEGALL");
                end

                fa=figure(); set(fa,'Name',dosyaadi) ;

                subplot(5,1,1)
                plot(Ttime,    EEGALL) ;
                xlabel('Time(sec)');ylabel('mVolt');title(' Notch & High Pass & Band Pass Filter time signal')

                subplot(5,1,2)
                [pxx,f] = pspectrum(EEGALL,fo);
                plot(f,pow2db(pxx));
                grid on;
                title("Single-Sided Amplitude Spectrum of Notch & High Pass & Band Pass Filter");xlabel("f (Hz)");ylabel("'Power/Frequency (dB/Hz)")


                % FFTEEGBPass= fft( EEGALL); %% Hpass FFT
                % fft_time= fo/L*(0:(L/2));
                % P2 = abs( FFTEEGBPass/L);
                % P1 = P2(1:L/2+1);
                % plot(fft_time,P1) ;set(gca,"yscale","log","YDir","normal");
                % title("Single-Sided Amplitude Spectrum of Notch & High Pass & Band Pass Filter");xlabel("f (Hz)");ylabel("|P(dB)|")

                subplot(5,1,3) ;
                nfft = 2^nextpow2(length(EEGALL));
                [s, f, t]=spectrogram(EEGALL ,hann(ws),ov,nfft,fo);
                val = 10*log10(abs(s));
                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(t,f,val);
                    ylim([f1 f2]);
                else
                    imagesc(t,f,val);
                end
                colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title('Notch & High Pass & Band Pass Filter Spectrogram');colorbar;
                CwtFilter=app.WaveletTypeListBox.Value;  
                subplot(5,1,4);
                [cff, f]=cwt(EEGALL,fo,CwtFilter);
                val=10*log10(abs(cff));
                ScalogramMatrix(:,:,1) = val;

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    freqMask = (f >= f1) & (f <= f2);
                    fselected = f(freqMask);
                    Selected_ScalogramMatrix = ScalogramMatrix(freqMask,:,:);
                    imagesc(Ttime,fselected,Selected_ScalogramMatrix);
                else
                    imagesc(Ttime,f,ScalogramMatrix);
                end

                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Notch & High Pass & Band Pass  Scalogram');colorbar;

                subplot(5,1,5);

                [psc,fsc,tsc]= pspectrum(EEGALL,fo,"spectrogram");

                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                    f1=app.F1PassFrequecyEditField.Value;
                    f2=app.F2PassFrequecyEditField.Value;
                    imagesc(tsc,fsc,psc);
                    ylim([f1 f2]);
                else
                    imagesc(tsc,fsc,psc);
                end

                set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                xlabel('Time (sec)');ylabel('Frequency(Hz)');title('Spectrum');colorbar;
           
        end

        function EEGLineFiltered=doFilterALL(app,fo, EEGraw)
            Fa=app.NotchFrequencyEditField.Value ; %  50 hz band durdurma frekansı 50 hz
            Q = app.Notch_Q_factorEditField.Value;
            Wo = Fa/(fo/2);
            BW = Wo/Q;
            [b,a] = iirnotch(Wo,BW);
            EEGNotch  = filtfilt(b,a,EEGraw');
            
            Fstop =app.StopBandFrequencyEditField.Value; % 0.0001;  % Stopband Frequency
            Fpass =app.PassbandFrequencyEditField.Value; % 0.0023;   % Passband Frequency
            Astop =app.HPStopGainEditField.Value; % 60;      % Stopband Attenuation (dB)
            Apass = app.HPPassGainEditField.Value; %1;       % Passband Ripple (dB)
            Fs=fo; 
            h = fdesign.highpass('fst,fp,ast,ap', Fstop, Fpass, Astop, Apass,Fs);
            Hd = design(h, 'equiripple',     'MinOrder', 'any',    'StopbandShape', 'flat');
            EEGNotchandHPass = filtfilt(Hd.Numerator,1,EEGNotch);

            F1stop =app.F1StopFrequecyEditField.Value;   % F1  Frequency
            F1pass =app.F1PassFrequecyEditField.Value;      % F1 Frequency
            F2pass =app.F2PassFrequecyEditField.Value;      % F2 Frequency
            F2stop =app.F2StopFrequecyEditField.Value;      % F2 Frequency
            ABPstop =app.BPStopGainEditField.Value;         % 60;      % Stopband Attenuation (dB)
            ABPpass = app.BPPassGainEditField.Value;        %1;       % Passband Ripple (dB)

            h = fdesign.bandpass('fst1,fp1,fp2,fst2,ast1,ap,ast2',  F1stop, F1pass , F2pass,  F2stop, ABPstop, ABPpass,  ABPstop, Fs);
            Hd = design(h, 'cheby2', 'MatchExactly','stopband');
           EEGLineFiltered= filtfilt(Hd.sosMatrix, Hd.ScaleValues,EEGNotchandHPass)';
       end

        

        function  doExperiment(app,GorDeneyNo,UygulamaSayisi,DeneyAdi,Sure,DeneyBasSuresi,DeneySuresi, ...
                fo,ws,ov,EEG,ToplamKanalAdi,SecilenKanalAdedi,filterName,ColorIndx, ...
                ExcelTxt,SPyol,SPyolDusuk,SCyol,SCyolDusuk,SPCyol,SPCyolDusuk,BMyol,BMyolDusuk, ...
                CwtFilter,deneyAdi,SecilenKanalNo, FiltreTipi,SecilenKanalAdi,SNRPath)
            kk=1;
            newResolution = [224, 224];
             [chanX, chanY] = do_25ch_1020_coords(app,ToplamKanalAdi);
          
            if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                f1=app.F1PassFrequecyEditField.Value;
                f2=app.F2PassFrequecyEditField.Value;
            end
            % Deney adına göre dizin oluşturup içine dosya yazmak için Excel dosyasındaki sütün bilgisi
            for  ii=1:GorDeneyNo    % deney sayısı

                for jj=1:UygulamaSayisi   % deneydeki uygulama sayısı
                    if  ismember(jj, [1, 2, 3, 4])
                        DGN =2;
                    elseif  ismember(jj, [5, 6, 7, 8])
                        DGN =6;
                    elseif  ismember (jj, [9, 10, 11, 12])
                        DGN=10;
                    elseif  ismember(jj, [13, 14, 15, 16])
                        DGN=14;
                    end

                    ResimDosyaAdi=DeneyAdi(kk,1);
                    if Sure(ii,jj)== 0
                        hataFileName=append(deneyAdi,'\','Error.txt');
                        fileID = fopen(hataFileName,'w');
                        fprintf(fileID,'No experiment =%s \n',ResimDosyaAdi);
                        fclose(fileID);
                    else
                        DStart=(Sure(ii,jj)*fo)+(DeneyBasSuresi*fo);
                        DStop=floor(DStart+((DeneySuresi-DeneyBasSuresi)*fo)-1);
                       % Data = (SecilenEEG(:,DStart:DStop));
                        Data25= (EEG(:,DStart:DStop));

                        if app.SaveMatCheckBox.Value== true
                           deneyAdi=app.ExperimentNameEditField.Value;
                            save(append(deneyAdi,'\Datalar\',string(ResimDosyaAdi)),"Data25");
                        end
                [N,~] = size(Data25);
                if app.LineFilter_EDFCheckBox.Value== true     %     Line filtre
                    SNR_ch = zeros(N, 1);
                    app.Labelx.Text='Line Filtering is processing Please WAIT';
                    switch FiltreTipi
                        case 1     % Notch Filter
                            Data25F=doFilterNotch(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 350  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch Filter EEG Channel SNR Values');xtickangle(90);
                                saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                        case 2   % HighPass Filt
                            Data25F=doFilterHighPass (app,fo,Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('High Pass Filter EEG Channel SNR Values');xtickangle(90);
                                saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                        case 3     %  Notch and HP
                            Data25F=doFilterNotchandHP(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch and High Pass  Filter EEG Channel SNR Values');xtickangle(90);
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                        case 4   % Band  pass
                            Data25F=doFilterBandPass(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Band Pass Filter EEG Channel SNR Values');xtickangle(90);
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                        case 5 % Notch and BP
                            Data25F=doFilterNotchandBP(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch and  Band Pass Filter EEG Channel SNR Values');xtickangle(90);
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                        case 6  % BP & HP
                            Data25F=doFilterBPandHP(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Band Pass & High Pass Filter EEG Channel SNR Values');xtickangle(90);
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                        case 7  % Notch & HP& BP
                            Data25F=doFilterALL(app, fo, Data25);
                            if app.Line_Filter_PerformanceCheckBox.Value==true % Filter SNR değer hesabı ve SNR grafiği
                                for i=1:N
                                    noise = Data25(i,:) - Data25F(i,:);
                                    Ps = sum(Data25F(i,:).^2);
                                    Pn = sum(noise.^2);
                                    SNR_ch(i,1) = 10 * log10(Ps / Pn);
                                end
                                figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                                bar(SNR_ch(SecilenKanalNo,:));
                                set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                                xlabel('EEG Channels'); ylabel('SNR (dB)');title('Notch & High & Band Pass Filter EEG Channel SNR Values');xtickangle(90);
                            saveas(gca, strcat(SNRPath,'LineFilter',ResimDosyaAdi,'.png'));
                            end
                            Data25=Data25F;
                    end
                end
                                
                if app.ArtifactFilter_EDFCheckBox.Value==true
                    app.Labelx.Text='Artifact Filter is prosessing Please WAIT';
                    Data25_ArtifactFiltered= doSobiFilter_determistic(app,Data25,ToplamKanalAdi,fo);
                    if app.Artifact_Filter_PerformanceCheckBox.Value==true
                        DeltaSNR_25ch = zeros(N,1);
                        SNR_before   = zeros(N,1);
                        SNR_after    = zeros(N,1);
                        epsVal = 1e-12;   % sayısal stabilite
                        for ch = 1:N
                            raw  = Data25(ch,:);
                            filt = Data25_ArtifactFiltered(ch,:);
                            Sraw  = raw  - mean(raw); % --- DC offset temizleme ---
                            Sfilt = filt - mean(filt);

                            artifact_before = Sraw;          % filtre öncesi
                            artifact_after  = Sraw - Sfilt;   % filtre sonrası

                            % --- Varyanslar ---
                            Ps_before = var(Sraw);
                            Pn_before = var(artifact_before);

                            Ps_after  = var(Sfilt);
                            Pn_after  = var(artifact_after);

                            if Pn_before < epsVal, Pn_before = epsVal; end% --- Koruma ---
                            if Pn_after  < epsVal, Pn_after  = epsVal; end

                            % --- SNR'ler ---
                            SNR_before(ch) = 10*log10(Ps_before / Pn_before);
                            SNR_after(ch)  = 10*log10(Ps_after  / Pn_after);
                            DeltaSNR_25ch(ch) = SNR_after(ch) - SNR_before(ch);
                        end

                        figure('Position',[650 350  560 420],'Name', ResimDosyaAdi);
                        bar(DeltaSNR_25ch(SecilenKanalNo,:));
                        set(gca, 'XTick', 1:SecilenKanalAdedi);set(gca, 'XTickLabel', SecilenKanalAdi);
                        xlabel('EEG Channels'); ylabel('SNR (dB)');title('Artifact Filter EEG Delta SNR Values');xtickangle(90);
                       saveas(gca, strcat(SNRPath,'ArtifacFilter',ResimDosyaAdi,'.png'));  
                           
                    end
                    Data25=  Data25_ArtifactFiltered;
                end
                Data=  Data25(SecilenKanalNo,:);

                        yol2=strcat(string(ExcelTxt(2,DGN)),string(ExcelTxt(3,jj+1)));
                        nfft = 2^nextpow2(length(Data));
                        Total_Spectrogram = []; % Tüm kanalların spektrogram değerlerini birleştirmek için
                        Total_Scalogram =[];
                        Total_Spoctrom=[];

                        if app.SpectrogramCheckBox.Value==true     % Her kanal için spektrogram hesabı
                         app.Labelx.Text=' spectrogram is prosessing Please WAIT';

                         for i = 1:SecilenKanalAdedi
                             switch   filterName
                                 case 'f_NoFilter'
                                     [s, f, ~]=spectrogram(Data(i,:)');
                                 case 'f_barthannwin'
                                     [s, f, ~]=spectrogram(Data(i,:)',barthannwin(ws),ov,nfft,fo);
                                 case 'f_bartlett'
                                     [s, f, ~]=spectrogram(Data(i,:)',bartlett(ws),ov,nfft,fo);
                                 case 'f_chebwin'
                                     [s, f, ~]=spectrogram(Data(i,:)',chebwin(ws),ov,nfft,fo);
                                 case 'f_hamming'
                                     [s, f, ~]=spectrogram(Data(i,:)',hamming(ws),ov,nfft,fo);
                                 case 'f_hann'
                                     [s, f, ~]=spectrogram(Data(i,:)',hann(ws),ov,nfft,fo);
                                 case 'f_kaiser'
                                     [s, f, ~]=spectrogram(Data(i,:)',kaiser(ws),ov,nfft,fo);
                                 case 'f_taylorwin'
                                     [s, f, ~]=spectrogram(Data(i,:)',taylorwin(ws),ov,nfft,fo);
                             end

                             val = 10*log10(abs(s)); % Güç spektrumu dB cinsinden

                             if app.ShowOnlySelectedFrequencyCheckBox.Value == true
                                 freqMask = (f >= f1) & (f <= f2);
                                 val_selected = val(freqMask, :); % frekanslara karşılık gelen sütunlar
                                 Total_Spectrogram = [Total_Spectrogram; val_selected]; % Seçilen frekans aralığındaki veriyi Total_S'ye ekle
                             else
                                 % Tüm frekans aralığını ekle
                                 Total_Spectrogram = [Total_Spectrogram; val];

                             end % selected frequency
                         end
                            % yol2=strcat(string(ExcelTxt(2,DGN)),string(ExcelTxt(3,jj+1)));
                          
                            figure('Position',[68 63  560 420],'Name', ResimDosyaAdi); %spectrogram
                           clims1 = [10 30];
                            
                            imagesc(Total_Spectrogram,clims1);
                            colormap  (ColorIndx)  ;   %spectrogram
                            ax2= gca;      %spectrogram
                            set(gca, 'Units', 'normalized', 'Position', [0 0 1 1]);
                            set(ax2,'YDir','normal');
                            set(get(ax2,'YAxis'),'Visible','off');%spectrogram
                            set(get(ax2,'XAxis'),'Visible','off');%spectrogram
                             
                            saveas(gca, strcat(SPyol,yol2,'\',ResimDosyaAdi,'.png'));             %spectrogram
                            originalImage_spectrogram = imread(strcat(SPyol,yol2,'\',ResimDosyaAdi,'.png'));  %spectrogram
                            resizedImage_spectrogram = imresize(originalImage_spectrogram , newResolution ); %spectrogram
                            ResizedPath_spectrogram= strcat(SPyolDusuk,yol2,'\',ResimDosyaAdi,'.png'); %spectrogram
                            imwrite(resizedImage_spectrogram,ResizedPath_spectrogram)
                        end   % spectrogramcheck


                        if app.ScalogramCWTCheckBox.Value==true  % Scologram
                            app.Labelx.Text=' Scalogram is prosessing Please WAIT';
                          
                            for i = 1:SecilenKanalAdedi
                                [cff, f]=cwt(Data(i,:),fo,CwtFilter);
                                val_Scalogram=10*log10(abs(cff));

                                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                                    freqMask = (f >= f1) & (f <= f2);
                                    val_selected_ScalogramMatrix =  val_Scalogram(freqMask,:);
                                    Total_Scalogram = [Total_Scalogram ; val_selected_ScalogramMatrix];
                                else
                                    Total_Scalogram = [Total_Scalogram ; val_Scalogram];
                                end
                            end
                            yol2=strcat(string(ExcelTxt(2,DGN)),string(ExcelTxt(3,jj+1)));
                            figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                            clims2 = [-10 8];
                            imagesc(Total_Scalogram,clims2 );
                            colormap  (ColorIndx)  ;
                            ax2= gca;      %spectrogram
                            set(gca, 'Units', 'normalized', 'Position', [0 0 1 1]);
                            set(ax2,'YDir','normal');
                            set(get(ax2,'YAxis'),'Visible','off');
                            set(get(ax2,'XAxis'),'Visible','off');
                          %  deneyAdi=app.ExperimentNameEditField.Value;
                            saveas(gca, strcat(SCyol,yol2,'\',ResimDosyaAdi,'.png'));
                            originalImage_spectrogram = imread(strcat(SCyol,yol2,'\',ResimDosyaAdi,'.png'));
                            resizedImage_spectrogram = imresize(originalImage_spectrogram , newResolution ); %spectrogram
                            ResizedPath_spectrogram= strcat(SCyolDusuk,yol2,'\',ResimDosyaAdi,'.png');
                            imwrite(resizedImage_spectrogram,ResizedPath_spectrogram)
                        end

                        if app.PSpectrumCheckBox.Value==true
                            app.Labelx.Text=' Pspectrum is prosessing Please WAIT';
                            for i = 1:SecilenKanalAdedi
                                [psc,f,~]= pspectrum(Data(i,:),fo,"spectrogram"); % Pspectrum
                                val_Spoctrom = 10*log10(abs(psc));

                                if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                                    freqMask = (f >= f1) & (f <= f2);
                                    Val_Selected_Spoctrom =val_Spoctrom(freqMask,:);
                                    Total_Spoctrom = [Total_Spoctrom ; Val_Selected_Spoctrom];
                                else
                                    Total_Spoctrom=[Total_Spoctrom ; val_Spoctrom];
                                end
                            end
                            yol2=strcat(string(ExcelTxt(2,DGN)),string(ExcelTxt(3,jj+1)));
                            figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                            clims3 = [-25 12];
                            imagesc(Total_Spoctrom,clims3);
                            colormap  (ColorIndx)  ;
                            ax2= gca;      %spectrogram
                            set(gca, 'Units', 'normalized', 'Position', [0 0 1 1]);
                            set(ax2,'YDir','normal');
                            set(get(ax2,'YAxis'),'Visible','off');
                            set(get(ax2,'XAxis'),'Visible','off');
                           saveas(gca, strcat(SPCyol,yol2,'\',ResimDosyaAdi,'.png'));
                            originalImage_spectrogram = imread(strcat(SPCyol,yol2,'\',ResimDosyaAdi,'.png'));
                            resizedImage_spectrogram = imresize(originalImage_spectrogram , newResolution ); %spectrogram
                            ResizedPath_spectrogram= strcat(SPCyolDusuk,yol2,'\',ResimDosyaAdi,'.png');
                            imwrite(resizedImage_spectrogram,ResizedPath_spectrogram)
                        end

                        if app.Topographic_mapCheckBox.Value==true
                            app.Labelx.Text=' Topomap is prosessing Please WAIT';
                            meanVals =sqrt(mean(Data25'.^2));
                            
                            gridRes = 140;
                            xr = linspace(-1,1,gridRes);
                            yr = linspace(-1,1,gridRes);
                            [Xg,Yg] = meshgrid(xr,yr);
                            mask = (Xg.^2 + Yg.^2 <= 1);

                            F = scatteredInterpolant(chanX, chanY,  meanVals', 'natural','nearest');
                            Z = F(Xg,Yg);
                            Z(~mask) = NaN;
                            figure('Position',[68 63  560 420],'Name', ResimDosyaAdi);
                            %    zlim([16 52])
                              % contourf(Xg,Yg,Z,[10 70],'ShowText','on'); hold on;
                            contourf(Xg,Yg,Z,50,'LineColor','none'); hold on;
                            axis equal off;

                            th = linspace(0,2*pi,200);
                            plot(cos(th), sin(th),'k','LineWidth',2);
                            colormap  (ColorIndx)  ;
                            ax2= gca;      %spectrogram
                            set(gca, 'Units', 'normalized', 'Position', [0 0 1 1]);
                            set(ax2,'YDir','normal');
                            set(get(ax2,'YAxis'),'Visible','off');
                            set(get(ax2,'XAxis'),'Visible','off');
                            % deneyAdi=app.ExperimentNameEditField.Value;
                            saveas(gca, strcat(BMyol,yol2,'\',ResimDosyaAdi,'.png'));
                            originalImage_spectrogram = imread(strcat(BMyol,yol2,'\',ResimDosyaAdi,'.png'));
                            resizedImage_spectrogram = imresize(originalImage_spectrogram , newResolution ); %spectrogram
                            ResizedPath_spectrogram= strcat(BMyolDusuk,yol2,'\',ResimDosyaAdi,'.png');
                            imwrite(resizedImage_spectrogram,ResizedPath_spectrogram)
                            hold off
                        end
                        close all;
                    end  
                    kk=kk+1;
                end
            end % UygulamaSayısı
        app.Labelx.Text='Experiment Finished';
        app.Lamp.Color="green";
      end %DeneyNo

        % SOBI ve RICA Artifact Filter
        function [EEG_clean]=doSobiFilter_determistic(app,EEG,chanLabels,fs)
            EEG=EEG';
            appliedChannel=app.ArtifactFilterReferenceListBox.Value;
            blinkIdx = find(strcmp(chanLabels,appliedChannel));

            t  = (1:length(EEG)).';
            rng(1,'twister'); %---- TÜM PIPELINE İÇİN GLOBAL RNG SABİT ----
          % rng(1);
            % SOBI ARTEFAKT FILTRE (DET)
            [EEG_sobi,~]=sobi_artifact_filter(EEG,fs,'blinkChanIdx',blinkIdx,'corrThr',0.6,'muscleBand',[20 40],'lowBand',[1 4]);

            % RICA ARTEFAKT FILTRE (DET, SABİT INIT)
            [EEG_clean, ~]=rica_artifact_filter_det(EEG_sobi,fs,'blinkChanIdx', blinkIdx,'numIC',25,'corrThr',0.6,'muscleBand',[20 40],'lowBand',[1 4]);

            function [EEG_clean, info] = sobi_artifact_filter(EEG, fs, varargin)
                p = inputParser;
                addParameter(p,'blinkChanIdx',[],@(x) isempty(x) || isscalar(x));
                addParameter(p,'corrThr',0.6,@isscalar);
                addParameter(p,'muscleBand',[20 40],@(x) isnumeric(x) && numel(x)==2);
                addParameter(p,'lowBand',[1 4],@(x) isnumeric(x) && numel(x)==2);
                parse(p,varargin{:});

                [~, Nc] = size(EEG);
                EEG_zm = detrend(EEG,'constant');   % [Ns x Nc]
                X = EEG_zm.';                       % [Nc x Ns]
                [W, S, A] = sobi_decompose(X);      % S: [Nc x Ns]
                IC = S.';                           % [Ns x Nc]
                artifactICs = [];
                blinkICs    = [];
                muscleICs   = [];

                % Blink IC tespiti
                if ~isempty(p.Results.blinkChanIdx)
                    blinkSig = EEG_zm(:, p.Results.blinkChanIdx);
                    r = zeros(1, Nc);
                    for k = 1:Nc
                        %rr = corr(IC(:,k), blinkSig);
                        rr = max(abs(xcorr(IC(:,k), blinkSig, 'coeff')));
                        if ~isnan(rr)
                            r(k) = rr;
                        end
                    end
                    blinkICs    = find(abs(r) > p.Results.corrThr);
                    artifactICs = union(artifactICs, blinkICs);
                end

                % Kas IC tespiti (gelişmiş)
                muscleICs = detect_muscle_ICs_advanced(IC, fs, p.Results.lowBand, p.Results.muscleBand);
                artifactICs = union(artifactICs, muscleICs);

                IC_clean = IC;
          %      IC_clean(:, artifactICs) = 0;
                IC_clean(:, artifactICs) = 0.1 * IC(:, artifactICs);
                
                S_clean = IC_clean.';          % [Nc x Ns]
                X_clean = A * S_clean;         % [Nc x Ns]
                EEG_clean = X_clean.';         % [Ns x Nc]

                info.IC          = IC;
                info.W           = W;
                info.A           = A;
                info.artifactICs = artifactICs;
                info.blinkICs    = blinkICs;
                info.muscleICs   = muscleICs;
            end

            function [EEG_clean, info] = rica_artifact_filter_det(EEG, fs, varargin)
                % Deterministik RICA: sabit RNG + sabit initial weights

                [~, Nc] = size(EEG);
                p = inputParser;
                addParameter(p,'blinkChanIdx',[],@(x) isempty(x) || isscalar(x));
                addParameter(p,'numIC',Nc,@isscalar);
                addParameter(p,'corrThr',0.6,@isscalar);
                addParameter(p,'muscleBand',[20 40],@(x) isnumeric(x) && numel(x)==2);
                addParameter(p,'lowBand',[1 4],@(x) isnumeric(x) && numel(x)==2);
                parse(p,varargin{:});

                blinkChanIdx = p.Results.blinkChanIdx;
                numIC = min(p.Results.numIC, size(EEG,2));

                EEG_zm = detrend(EEG,'constant');   % [Ns x Nc]

                % ---- DETERMINISTIK INITIAL WEIGHTS ----
                % (Global rng zaten sabit; burada ekstra rng çağrısı yok)
                initW = randn(numIC, size(EEG,2));         % [numIC x Nc] sabit pattern

                % RICA modeli
                %2iterationLimit
                Mdl = rica(EEG_zm, numIC, 'InitialTransformWeights', initW, 'IterationLimit', 500, ...
                    'GradientTolerance', 1e-6, 'StepTolerance', 1e-6);

                % IC: [Ns x numIC]
                IC = transform(Mdl, EEG_zm);

                artifactICs = [];
                blinkICs    = [];
                muscleICs   = [];

                % Blink IC tespiti
                if ~isempty(blinkChanIdx)
                    blinkSig = EEG_zm(:, blinkChanIdx);
                    r = zeros(1, numIC);
                    for k = 1:numIC
                        rr = corr(IC(:,k), blinkSig);
                        if ~isnan(rr)
                            r(k) = rr;
                        end
                    end
                    blinkICs    = find(abs(r) > p.Results.corrThr);
                    artifactICs = union(artifactICs, blinkICs);
                end

                % Kas IC tespiti (gelişmiş)
                muscleICs = detect_muscle_ICs_advanced(IC, fs, p.Results.lowBand, p.Results.muscleBand);
                artifactICs = union(artifactICs, muscleICs);

                IC_clean = IC;
                IC_clean(:, artifactICs) = 0;

                % EEG_clean: IC_clean * W'
               % EEG_clean = IC_clean * Mdl.TransformWeights.';   % [Ns x Nc]
               W = Mdl.TransformWeights;
                EEG_clean = IC_clean * pinv(W);
              % EEG_clean = IC_clean * pinv(Mdl.TransformWeights);
                
                info.Mdl         = Mdl;
                info.IC          = IC;
                info.IC_clean    = IC_clean;
                info.artifactICs = artifactICs;
                info.blinkICs    = blinkICs;
                info.muscleICs   = muscleICs;
            end

            function idx = detect_muscle_ICs_advanced(IC, fs, lowBand, muscleBand)
                % Kas IC tespiti:
                %  - muscleBand / lowBand güç oranı
                %  - spektral entropi
                %  - kurtosis

                [Ns, Nc] = size(IC);
                idx = [];

                for k = 1:Nc
                    x = IC(:,k);
                    [pxx,f] = pwelch(x, fs, fs/2, fs, fs);

                    lowPow    = bandpower(pxx, f, 'psd', lowBand);
                    musclePow = bandpower(pxx, f, 'psd', muscleBand);
                    ratio     = musclePow / (lowPow + eps);

                    p = pxx / (sum(pxx) + eps);
                    specEntropy = -sum(p .* log(p + eps)) / log(numel(p));
                    krt = kurtosis(x);

                  %  if (ratio > 3) || (ratio > 2 && krt > 4 && specEntropy > 0.4)
                  if ratio > mean(ratio)+2*std(ratio)
                      idx = [idx k];
                  end
                end
            end
            function [W, S, A] = sobi_decompose(X)
                % Basit SOBI implementasyonu (joint diagonalization) — deterministik
                [Nc,Ns] = size(X);

                X = X - mean(X,2);
                Cx = cov(X.');
                % [E,D] = eig(Cx);
                %    Ww = diag(1./sqrt(diag(D))) * E';
                [E,D] = eig(Cx);
                [d,idx] = sort(diag(D),'descend');
                E = E(:,idx);
                D = diag(d);

                Ww = diag(1./sqrt(d + eps)) * E';
            
            Xw = Ww * X;

                taus = [1 2 3 5 7 10 13];

                R = zeros(Nc,Nc,length(taus));
                for i = 1:length(taus)
                    tau = taus(i);
                    R(:,:,i) = (Xw(:,1+tau:Ns) * Xw(:,1:Ns-tau)') / (Ns - tau);
                end

                V = eye(Nc);
                maxIter = 50;

                for it = 1:maxIter
                    for p = 1:Nc-1
                        for q = p+1:Nc
                            g11=0; g22=0; g12=0; g21=0;
                            for i = 1:length(taus)
                                Rp  = R(p,p,i); Rq  = R(q,q,i);
                                Rpq = R(p,q,i); Rqp = R(q,p,i);
                                g11 = g11 + Rp - Rq;
                                g22 = g22 - (Rp - Rq);
                                g12 = g12 + Rpq + Rqp;
                                g21 = g21 + Rpq + Rqp;
                            end
                            theta = 0.5 * atan2(g12-g21, g11-g22);

                            G = eye(Nc);
                            G([p q],[p q]) = [cos(theta) -sin(theta); sin(theta) cos(theta)];

                            V = G' * V;
                            for i = 1:length(taus)
                                R(:,:,i) = G' * R(:,:,i) * G;
                            end
                        end
                    end
                end

                W = V * Ww;
                S = W * X;
                A = pinv(W);
            end
            EEG_clean=EEG_clean';
        end

        function [EEGKanalNo,KanalAdedi,KanalAdi] = doAddElectrodesLocation(app)
            KanalAdedi=0;KanalAdi=[];

            if app.C3CheckBox.Value==true % c3
                EEGKanalNo(1)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"C3"];
            else
                EEGKanalNo(1)=0;
            end

            if app.C4CheckBox.Value==true %C4
                EEGKanalNo(2)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"C4"];
            else
                EEGKanalNo(2)=0;
            end

            if app.CZCheckBox.Value==true  %CZ
                EEGKanalNo(3)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Cz"];
            else
                EEGKanalNo(3)=0;
            end

            if app.F3CheckBox.Value==true % F3
                EEGKanalNo(4)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"F3"];
            else
                EEGKanalNo(4)=0;
            end

            if app.F4CheckBox.Value==true   % F4
                EEGKanalNo(5)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"F4"];
            else
                EEGKanalNo(5)=0;
            end

            if app.F7CheckBox.Value==true %F7
                EEGKanalNo(6)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"F7"];
            else
                EEGKanalNo(6)=0;
            end

            if app.F8CheckBox.Value==true %F8
                EEGKanalNo(7)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"F8"];
            else
                EEGKanalNo(7)=0;
            end

            if app.FZCheckBox.Value==true %Fz
                EEGKanalNo(8)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Fz"];
            else
                EEGKanalNo(8)=0;
            end

            if app.FP1CheckBox.Value==true %FP1
                EEGKanalNo(9)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Fp1"];
            else
                EEGKanalNo(9)=0;
            end

            if app.FP2CheckBox.Value==true %FP2
                EEGKanalNo(10)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Fp2"];

            else
                EEGKanalNo(10)=0;
            end

            if app.FPZCheckBox.Value==true %FPZ
                EEGKanalNo(11)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Fpz"];
            else
                EEGKanalNo(11)=0;
            end

            if app.A1CheckBox.Value==true %A1
                EEGKanalNo(12)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"A1"];
            else
                EEGKanalNo(12)=0;
            end

            if app.A2CheckBox.Value==true %A2
                EEGKanalNo(13)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"A2"];
            else
                EEGKanalNo(13)=0;
            end

            if app.O1CheckBox.Value==true %O1
                EEGKanalNo(14)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"O1"];
            else
                EEGKanalNo(14)=0;
            end

            if app.O2CheckBox.Value==true %O2
                EEGKanalNo(15)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"O2"];
            else
                EEGKanalNo(15)=0;
            end

            if app.OZCheckBox.Value==true %OZ
                EEGKanalNo(16)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Oz"];
            else
                EEGKanalNo(16)=0;
            end

            if app.P3CheckBox.Value==true %P3
                EEGKanalNo(17)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"P3"];
            else
                EEGKanalNo(17)=0;
            end

            if app.P4CheckBox.Value==true %P4
                EEGKanalNo(18)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"P4"];
            else
                EEGKanalNo(18)=0;
            end

            if app.T5CheckBox.Value==true %T5
                EEGKanalNo(19)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T5"];
            else
                EEGKanalNo(19)=0;
            end

            if app.T6CheckBox.Value==true %T6
                EEGKanalNo(20)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T6"];
            else
                EEGKanalNo(20)=0;
            end

            if app.PZCheckBox.Value==true %PZ
                EEGKanalNo(21)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"Pz"];
            else
                EEGKanalNo(21)=0;
            end

            if app.T3CheckBox.Value==true %T3
                EEGKanalNo(22)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T3"];
            else
                EEGKanalNo(22)=0;
            end

            if app.T4CheckBox.Value==true %T4
                EEGKanalNo(23)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T4"];
            else
                EEGKanalNo(23)=0;
            end

            if app.T1CheckBox.Value==true %T1
                EEGKanalNo(24)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T1"];
            else
                EEGKanalNo(24)=0;
            end

            if app.T2CheckBox.Value==true %T2
                EEGKanalNo(25)=1;
                KanalAdedi=KanalAdedi+1;
                KanalAdi=[KanalAdi,"T2"];
            else
                EEGKanalNo(25)=0;
            end
        end
        
        % secilen filter tipine göre filter parametrelerini ekle
        function  FiltreTipi=doLineFilterType(app)
            % global FiltreTipi
            if  app.NotchCheckBox.Value==true
                f1=1;  % Notch Filter
            else
                f1=0;
            end

            if app.HighPassCheckBox.Value==true
                f2=2; % High Pass Filter
            else
                f2=0;
            end

            if app.BandPassCheckBox.Value==true
                f3=4; % Band Pass Filter
            else
                f3=0;
            end
            FiltreTipi=f1+f2+f3;
        end

        function  doBeniOku(app,deneyAdi,HastaAdi,DeneyBasSuresi,DeneySuresi,FiltreTipi,WindowSize,...
                ov_size,filterName,CwtFilter,ColorIndx,SecilenKanalAdi)

            DosyaAdi=append(deneyAdi,'\','Readme.txt');
            fileID = fopen(DosyaAdi,'w');

            if app.SelectPatientCheckBox.Value==true
                fprintf(fileID, 'Hasta Adı = %s\n', string(HastaAdi));
            else
                for i=3:height(HastaAdi)
                    HAdi=cellstr(table2cell(HastaAdi(i,1)));
                    fprintf(fileID, 'Patient Name = %s\n', string(HAdi));
                end
            end

            fprintf(fileID,'Experiment Starting Time (sec) =%d \n',DeneyBasSuresi);
            fprintf(fileID,'Experiment Time (sec) =%d \n',DeneySuresi);


            switch FiltreTipi  % casen8 ve case 15 arası artifact filtre için yapılacak
                case 0
                    fprintf(fileID,'Line Filtre Selected\n');
                case 1
                    fprintf(fileID,'Notch Filter Selected\n');
                    fprintf(fileID,'Notch Frequency= %d\n',app.NotchFrequencyEditField.Value);
                    fprintf(fileID,'Notch Q Factor= %d\n',app.Notch_Q_factorEditField.Value);
                case 2
                    fprintf(fileID,'High Pass Filter Selectedi\n');
                    fprintf(fileID,'Stop Band Frequency=%\n',app.StopBandFrequencyEditField.Value);
                    fprintf(fileID,'Pass Band Frequencyı=%\n',app.PassbandFrequencyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%\n',app.HPStopGainEditField.Value);
                    fprintf(fileID,'Pass Band Gain= %\n',app.HPPassGainEditField.Value);
                case 3
                    fprintf(fileID,'Notch ve High Pass Filter Selected\n');
                    fprintf(fileID,'Notch Frequency= %d\n',app.NotchFrequencyEditField.Value);
                    fprintf(fileID,'Notch Q Factor= %d\n',app.Notch_Q_factorEditField.Value);
                    fprintf(fileID,'Stop Band Frequency=%d\n',app.StopBandFrequencyEditField.Value);
                    fprintf(fileID,'Pass Band Frequencyı=%d\n',app.PassbandFrequencyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.HPStopGainEditField.Value);
                    fprintf(fileID,'Pass Band Gain= %d\n',app.HPPassGainEditField.Value);
                case 4
                    fprintf(fileID,'Band Pass Filter Selected\n');
                    fprintf(fileID,'F1 Stopband Frequency= %d\n',app.F1StopFrequecyEditField.Value);
                    fprintf(fileID,'F1 Passband Frequency= %d\n',app.F1PassFrequecyEditField.Value);
                    fprintf(fileID,'F2 Stopband Frequency= %d\n',app.F2StopFrequecyEditField.Value);
                    fprintf(fileID,'F2 Passband Frequency= %d\n',app.F2PassFrequecyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.BPStopGainEditField.Value);
                    fprintf(fileID,'Band Pass Gain=%d\n',app.BPPassGainEditField.Value);
                case 5
                    fprintf(fileID,'Notch ve Band Pass Filter Selected\n');
                    fprintf(fileID,'Notch Frekansı= %d\n',app.NotchFrequencyEditField.Value);
                    fprintf(fileID,'Notch Q Factor= %d\n',app.Notch_Q_factorEditField.Value);
                    fprintf(fileID,'F1 Stopband Frequency= %d\n',app.F1StopFrequecyEditField.Value);
                    fprintf(fileID,'F1 Passband Frequency= %d\n',app.F1PassFrequecyEditField.Value);
                    fprintf(fileID,'F2 Stopband Frequency= %d\n',app.F2StopFrequecyEditField.Value);
                    fprintf(fileID,'F2 Passband Frequency= %d\n',app.F2PassFrequecyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.BPStopGainEditField.Value);
                    fprintf(fileID,'Band Pass Gain=%d\n',app.BPPassGainEditField.Value);
                case 6
                    fprintf(fileID,'High Pass ve Band Pass Filter Selected\n');
                    fprintf(fileID,'Stop Band Frequency=%d\n',app.StopBandFrequencyEditField.Value);
                    fprintf(fileID,'Pass Band Frequency=%d\n',app.PassbandFrequencyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.HPStopGainEditField.Value);
                    fprintf(fileID,'Pass Band Gain= %d\n',app.HPPassGainEditField.Value);
                    fprintf(fileID,'F1 Stopband Frequency= %d\n',app.F1StopFrequecyEditField.Value);
                    fprintf(fileID,'F1 Passband Frequency= %d\n',app.F1PassFrequecyEditField.Value);
                    fprintf(fileID,'F2 Stopband Frequency= %d\n',app.F2StopFrequecyEditField.Value);
                    fprintf(fileID,'F2 Passband Frequency= %d\n',app.F2PassFrequecyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.BPStopGainEditField.Value);
                    fprintf(fileID,'Band Pass Gain=%d\n',app.BPPassGainEditField.Value);
                case 7
                    fprintf(fileID,'Notch,High Pass ve Band Pass Filter Selected\n');
                    fprintf(fileID,'Notch Frequency= %d\n',app.NotchFrequencyEditField.Value);
                    fprintf(fileID,'Notch Q Factor= %d\n',app.Notch_Q_factorEditField.Value);
                    fprintf(fileID,'Stop Band Frequency=%d\n',app.StopBandFrequencyEditField.Value);
                    fprintf(fileID,'Pass Band Frequency=%d\n',app.PassbandFrequencyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.HPStopGainEditField.Value);
                    fprintf(fileID,'Pass Band Gain= %d\n',app.HPPassGainEditField.Value);
                    fprintf(fileID,'F1 Stopband Frequency= %d\n',app.F1StopFrequecyEditField.Value);
                    fprintf(fileID,'F1 Passband Frequency= %d\n',app.F1PassFrequecyEditField.Value);
                    fprintf(fileID,'F2 Stopband Frequency= %d\n',app.F2StopFrequecyEditField.Value);
                    fprintf(fileID,'F2 Passband Frequency= %d\n',app.F2PassFrequecyEditField.Value);
                    fprintf(fileID,'Stop Band Gain=%d\n',app.BPStopGainEditField.Value);
                    fprintf(fileID,'Band Pass Gain=%d\n',app.BPPassGainEditField.Value);
            end

            if app.ArtifactFilter_EDFCheckBox.Value==true
                fprintf(fileID,'Artifact Filer Selected \n');
            else
                fprintf(fileID,'Artifact Filer Not Selected \n');
            end
            fprintf(fileID,'WindowSize =%d \n',WindowSize);
            fprintf(fileID,'ov_size=%d \n',ov_size);
            fprintf(fileID,'Spectrogaram Filter Type =%s \n',filterName);
            fprintf(fileID,'Wavelet Filter Type= %s \n',CwtFilter);
            fprintf(fileID,'Selected EEG Channels=%s \n',SecilenKanalAdi);
            fprintf(fileID,'Color Code =%s \n',ColorIndx);
            fclose(fileID);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LineFilterButton
        function LineFilterButtonPushed(app, event)
         
            if app.SaveChannelFilterOutCheckBox.Value== true
                deneyAdi=app.ExperimentNameEditField.Value;
                mkdir(append(deneyAdi,'\FilterDatalar')) ;
            end
            FiltreTipi=doLineFilterType(app);
            app.Lamp.Visible=true;
            app.Lamp.Color="red";
            app.Labelx.Text='Line Filter is prosessing Please WAIT';

            switch FiltreTipi  
                case 0
                  app.Labelx.Text='No Filter Selected WAIT';
                   doFilterDrawRaw(app);
                case 1
                    app.Labelx.Text='Notch Filter is prosessing Please WAIT';
                    doFilterDrawNotch(app);  % Notch Filter
                case 2
                    app.Labelx.Text='High Pass Filter is prosessing Please WAIT';
                    doFilterDrawHighPass(app); % HighPass Filter
                case 3
                    app.Labelx.Text='Notch and High Pass Filter is prosessing Please WAIT';
                    doFilterDrawNotchandHP(app);   %  Notch and HP
                case 4
                    app.Labelx.Text='Band Pass Filter is prosessing Please WAIT';
                    doFilterDrawBandPass(app) ; % Band  pass
                case 5
                    app.Labelx.Text='Notch and Band Pass Filter is prosessing Please WAIT';
                    doFilterDrawNotchandBP(app); % Notch and BP
                case 6
                    app.Labelx.Text='Band Pass and High Pass Filter is prosessing Please WAIT';
                    doFilterDrawBPandHP(app); % BP & HP
                case 7
                    app.Labelx.Text='ALL Line Filter is prosessing Please WAIT';
                    doFilterDrawALL(app) ; % Nocth & HP& BP
            end

            
            app.Lamp.Color="green";
               app.Labelx.Text='Experiment Finished';
        end

        % Button pushed function: Inspect_EDFButton
        function Inspect_EDFButtonPushed(app, event)
            app.Lamp.Visible=true;
            app.Lamp.Color="red";
            warning off;
            app.Labelx.Text='EDF is prosessing Please WAIT';
            [file, path] = uigetfile({'*.edf', 'edf (*.edf'}, 'Bir .edf dosyası seçin');
            if isequal(file, 0)
                disp('Dosya seçilmedi.');
            else
                fullPath = fullfile(path, file);
                app.Label2.Text= fullPath;
                dosya=edfread(fullPath);
                info = edfinfo(fullPath);
                fs=info.NumSamples(1,1);                   %  Bir satırdaki kayıt miktarı,
                sr=dosya.Properties.SampleRate;       % her bir saniye için örnekleme sayısı
                fo=sr*fs;                                                 % örnekleme frekansı (Hz) fo=sr*fs
                fk=info.NumDataRecords;                  % Toplam satır sayısı
                fktotal=fk*fo;                                         % Toplam kayıt adedi (adet)
                [~, DosyaAdi, ~] = fileparts(file);
                current_path =pwd;
                deneySure=(app.Experiment_DurationEditField.Value-app.Idle_TimeEditField.Value);
                ToplamEEGKanalNo=[1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1];
                ToplamEEGKanalAdedi=25;
                ToplamEEGKanalAdi ={'C3','C4','CZ','F3','F4','F7','F8','FZ', 'FP1','FP2','FPZ','A1'...
                    'A2','O1','O2','OZ','P3','P4','T5','T6','PZ','T3','T4','T1','T2'};
                ToplamEEG=doEEGOku(app,dosya,ToplamEEGKanalNo,ToplamEEGKanalAdedi,fktotal,fk,fo);
                [ExcelSure, ExcelTxt] = xlsread(strcat(current_path,'\EXCEL\',DosyaAdi,'.xlsx'));
                dtime=(0:(size(ToplamEEG'))-1)/fo;
                [SecilenKanalNo,SecilenKanalAdedi,~] = doAddElectrodesLocation(app);
                 
                if app.LineFilterCheckBox.Value== true     %     Line filtre
                    FiltreTipi=doLineFilterType(app); % line filtre tipi
                    app.Labelx.Text='Line Filterin is prosessing Please WAIT';
                    switch FiltreTipi
                        case 1     % Notch Filter
                            ToplamEEG=doFilterNotch(app, fo, ToplamEEG);
                        case 2   % HighPass Filt
                            ToplamEEG=doFilterHighPass (app,fo,ToplamEEG);
                        case 3     %  Notch and HP
                            ToplamEEG=doFilterNotchandHP(app, fo, ToplamEEG);
                        case 4   % Band  pass
                            ToplamEEG=doFilterBandPass(app, fo, ToplamEEG);
                        case 5 % Notch and BP
                            ToplamEEG=doFilterNotchandBP(app, fo, ToplamEEG);
                        case 6  % BP & HP
                            ToplamEEG=doFilterBPandHP(app, fo,ToplamEEG);
                        case 7  % Nocth & HP& BP
                            ToplamEEG=doFilterALL(app, fo, ToplamEEG);
                    end
                end
               
                if app.ArtifacFilterCheckBox.Value==true  % artifact Filter
                    app.Labelx.Text='Artifact Filtering is prosessing Please WAIT';
                    [ToplamEEG]=doSobiFilter_determistic(app,ToplamEEG,ToplamEEGKanalAdi,fo);
                end

                aaa = {};    % Deney adedi 
                DeneyNumarasi=str2double(app.ExperimentNoListBox.Value);
                if  DeneyNumarasi==0   % eğer 0 ise bütün deneyleri yap
                    DeneyBasNumarasi=1;
                    DeneyBitNumarasi=6;
                    Sure=reshape(ExcelSure.', DeneyBitNumarasi*16, DeneyBasNumarasi);
                else
                    Sure=ExcelSure(DeneyNumarasi,:)';
                    DeneyBasNumarasi=DeneyNumarasi;
                    DeneyBitNumarasi=DeneyNumarasi;
                end

                for ii=DeneyBasNumarasi: DeneyBitNumarasi
                    for j = 2:4:16
                        for k = 2:5 % Hareket 2–5 arasında (up, down, open, close) % Hücreden metinleri oku (örnek: Deney adı, grup adı, hareket adı)
                            deneyStr   = string(ExcelTxt{ii+3,1});
                            grupStr    = string(ExcelTxt{2,j});
                            hareketStr = string(ExcelTxt{3,k});
                            newName = deneyStr+grupStr +hareketStr;
                            aaa{end+1,1} = newName;
                        end
                    end
                end
           
             if app.Show_Only_Selected_ChannelsCheckBox.Value==true
                    SecilenKanalNo=boolean(SecilenKanalNo);
                    SecilenEEG=  ToplamEEG(SecilenKanalNo,:);
                    SecilenKanalAdi=ToplamEEGKanalAdi(SecilenKanalNo);
             else
                    SecilenEEG=  ToplamEEG;
                    SecilenKanalAdi=ToplamEEGKanalAdi;
                    SecilenKanalAdedi=ToplamEEGKanalAdedi;
             end
         
               Sure(:,2)=Sure(:,1)+ deneySure;
               colors = lines(height(aaa));  % hareket renkleri
               offset = max(std(SecilenEEG,0,2)) * 4;   % kanal aralığı (EEGLAB benzeri)
               
                figure('Name',DosyaAdi);   % Artifact EEG
                hold on;
                for ch = 1:SecilenKanalAdedi
                    plot(dtime, SecilenEEG(ch,:) + (SecilenKanalAdedi-ch)*offset, 'k', 'LineWidth', 0.8);
                end
                yticks((0:SecilenKanalAdedi-1)*offset); yticklabels(flip(SecilenKanalAdi));
                ylabel('Channel');xlabel('Time(sec)');  title(' Channels & Movements');
                set(gca,'YDir','normal');grid off;box on;
                xlim([dtime(1) dtime(end)]);
                yLimits = ylim;
                for i = 1:height(aaa)
                    x1 =( Sure(i,1));% T.baslangic_saniye(i);
                    x2 =( Sure(i,2));% T.bitis_saniye(i);
                    h2 = patch([x1 x2 x2 x1], [yLimits(1) yLimits(1) yLimits(2) yLimits(2)],colors(i,:),'FaceAlpha',0.2,'EdgeColor','none');
                    text((x1+x2)/2, yLimits(2)*0.5, aaa{i},'rotation',90, ...
                      'HorizontalAlignment','center', 'FontSize',9,'color','red','FontWeight','bold');
                end
 
                if app.ShowChannel_FrquencPowerCheckBox.Value== true    % Kanalların time ve FFT grafikleri
                    doPlot_IC_panels(app,SecilenEEG',SecilenKanalAdi,fo,DosyaAdi,dtime);
                end

                if app.MakeVideoCheckBox.Value==true  % SCROLLING EEG + RAW/CLEAN SCALP VIDEO (20 FPS, 1280x720)
                   app.Labelx.Text='Video is prosessing Please WAIT';
                    deneyAdi=app.ExperimentNameEditField.Value;
                   DosyaAdi=append(deneyAdi,'\',DosyaAdi);
                    [chanX, chanY] = do_25ch_1020_coords(app,ToplamEEGKanalAdi);
                    doCreatevideo(app,ToplamEEG',SecilenEEG',chanX, chanY, ToplamEEGKanalAdi, SecilenKanalAdi, fo, DosyaAdi,aaa,Sure,dtime,deneySure);
                end

                if app.SaveRawEEGCheckBox.Value==true   % Çalıştırmadan sadece Filtrelenmiş EEG datasını sakla
                    deneyAdi=app.ExperimentNameEditField.Value;
                    mkdir(append(deneyAdi,'\FilterDatalar')) ;
                    save(append(deneyAdi,'\FilterDatalar\',string(file),'.mat'), "ToplamEEG");
                end

            app.Lamp.Color = "green";
            app.Labelx.Text='Experiment Finished';
            end
        end

        % Selection changed function: EEG_BandsButtonGroup
        function EEG_BandsButtonGroupSelectionChanged(app, event)
            if app.DeltaButton.Value==true
                app.F1StopFrequecyEditField.Value=0.01;
                app.F1PassFrequecyEditField.Value=0.5;
                app.F2PassFrequecyEditField.Value=4;
                app.F2StopFrequecyEditField.Value=4.1;
            end
            if app.ThetaButton.Value==true
                app.F1StopFrequecyEditField.Value=3.9;
                app.F1PassFrequecyEditField.Value=4;
                app.F2PassFrequecyEditField.Value=8;
                app.F2StopFrequecyEditField.Value=8.1;
            end
            if app.AlphaButton.Value==true
                app.F1StopFrequecyEditField.Value=7.9;
                app.F1PassFrequecyEditField.Value=8;
                app.F2PassFrequecyEditField.Value=13;
                app.F2StopFrequecyEditField.Value=13.2;
            end
            if app.BetaButton.Value==true
                app.F1StopFrequecyEditField.Value=12.9;
                app.F1PassFrequecyEditField.Value=13;
                app.F2PassFrequecyEditField.Value=30;
                app.F2StopFrequecyEditField.Value=30.1;
            end
            if app.GamaButton.Value==true
                app.F1StopFrequecyEditField.Value=29;
                app.F1PassFrequecyEditField.Value=30;
                app.F2PassFrequecyEditField.Value=100;
                app.F2StopFrequecyEditField.Value=101;
            end
            if app.UserDefinedButton.Value==true
                app.F1StopFrequecyEditField.Value=7.9;
                app.F1PassFrequecyEditField.Value=8;
                app.F2PassFrequecyEditField.Value=30;
                app.F2StopFrequecyEditField.Value=30.1;
            end
        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            if app.NotchCheckBox.Value==true
                app.NotchFrequencyEditField.Enable=true;
                app.Notch_Q_factorEditField.Enable=true;
            else
                app.NotchFrequencyEditField.Enable=false;
                app.Notch_Q_factorEditField.Enable=false;
            end
        end

        % Value changed function: HighPassCheckBox
        function HighPassCheckBoxValueChanged(app, event)
            if app.HighPassCheckBox.Value== true
                app.StopBandFrequencyEditField.Enable=true;
                app.PassbandFrequencyEditField.Enable=true;
                app.HPPassGainEditField.Enable=true;
                app.HPStopGainEditField.Enable=true;
            else
                app.StopBandFrequencyEditField.Enable=false;
                app.PassbandFrequencyEditField.Enable=false;
                app.HPPassGainEditField.Enable=false;
                app.HPStopGainEditField.Enable=false;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.RUNButton.BackgroundColor='red';

            warning('off');
            app.Lamp.Visible=true;
            app.Lamp.Color="red";
            app.Labelx.Text='EDF file is prosessing Please WAIT';

            FiltreTipi=doLineFilterType(app); % line filtre tipi
            DeneyBasSuresi=app.Idle_TimeEditField.Value;
            DeneySuresi=app.Experiment_DurationEditField.Value;
            GorDeneyNo=app.Total_ExperimentEditField.Value;

            UygulamaSayisi=app.Movement_NumberEditField.Value;
            WindowSize=app.Windows_SizeEditField.Value;
            ov_size=app.OverlapEditField.Value;
            ws=WindowSize;
            ov=ov_size;
            filterName=app.SpectrogramFilterTypeListBox.Value;  % spectragram filter type
            ColorIndx=app.ColorListBox.Value;   % spactrogram and scolagarm filter
            ToplamEEGKanalNo=[1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1,1];
            ToplamKanalAdedi=25;
            ToplamKanalAdi=["C3","C4","CZ","F3","F4","F7","F8","FZ","FP1","FP2","FPZ","A1","A2","O1","O2","OZ","P3","P4","T5","T6","PZ","T3","T4","T1","T2"];
            [SecilenKanalNo,SecilenKanalAdedi,~] = doAddElectrodesLocation(app); % Elektrodları oku
            CwtFilter=app.WaveletTypeListBox.Value;
            SecilenKanalNo=boolean(SecilenKanalNo);
            SecilenKanalAdi=ToplamKanalAdi(SecilenKanalNo);
            deneyAdi=app.ExperimentNameEditField.Value;
            current_path =pwd ;
            DizinAdi=append(current_path,'\',deneyAdi);
            
            [SNRPath]=doSNRDirectory( app,DizinAdi);
            [SPyol, SPyolDusuk]=doSpectrumDirectory(app, DizinAdi ,filterName,ws,ov,SecilenKanalAdedi,DeneySuresi);
            [SCyol, SCyolDusuk]=doScalogramDirectory( app,DizinAdi ,SecilenKanalAdedi,DeneySuresi);
            [SPCyol, SPCyolDusuk]=doPSpectrumDirectory( app,DizinAdi ,SecilenKanalAdedi,DeneySuresi); % dospcotrom
            [BMyol,BMyolDusuk]=doBrainMapDirectory( app,DizinAdi, DeneySuresi);

            if app.SaveMatCheckBox.Value== true
                deneyAdi=app.ExperimentNameEditField.Value;
                mkdir(append(deneyAdi,'\Datalar')) ;
            end

            if app.SelectPatientCheckBox.Value==true       % Tek bir hasta için
                [EdfAdi,edf_yolu] = uigetfile({'*.edf', 'edf (*.edf'}, 'Bir .edf dosyası seçin');
                if isequal(EdfAdi,0)
                    return; % Kullanıcı iptal etti
                end
                [~, DosyaAdi, ~] = fileparts( EdfAdi);
                HastaAdi=DosyaAdi;
                satir=3;
            else
                edf_yolu = append(current_path, '\EDF');
                AllFile=struct2table(dir(edf_yolu));
                HastaAdi=AllFile;
                [satir,~]=size(struct2table(dir(append(current_path, '\EDF'))));      % \EDF dizinindeki kayıt adedi ( satır sayısından 2 eksik)
            end

           doBeniOku(app,deneyAdi,HastaAdi,DeneyBasSuresi,DeneySuresi,FiltreTipi,WindowSize,...
          ov_size,filterName,CwtFilter,ColorIndx,SecilenKanalAdi)


            for mmm=3:satir                                   % Edf dizindeki toplam dosya saysı kadar döngü
                if app.SelectPatientCheckBox.Value==false
                    EdfAdi=string(cellstr(table2cell(AllFile(mmm,1))));
                    [~, DosyaAdi, ~] = fileparts( EdfAdi);
                end
                app.Label2.Text= EdfAdi;
                app.Labelx.Text='File open';
                dosya=edfread(append(edf_yolu,'\',EdfAdi));
                info = edfinfo(append(edf_yolu,'\',EdfAdi));
                Fs=info.NumSamples(1,1);                   %  Bir satırdaki kayıt miktarı,
                sr=dosya.Properties.SampleRate;       % her bir saniye için örnekleme sayısı
                fo=sr*Fs;                                                 % örnekleme frekansı (Hz) fo=sr*fs
                fk=info.NumDataRecords;                  % Toplam satır sayısı
                fktotal=fk*fo;                                         % Toplam kayıt adedi (adet)
                ftotal=fk/sr;                                           % Toplam kayıt süresi (saniye)
                app.F_RecordLabel.Text=string(fktotal);
                app.F_TimeLabel.Text=string(ftotal);
                app.F_PatientNameLabel.Text=EdfAdi;
                app.F_SamplingLabel.Text=string(fo);
              
              %  ws=fo*WindowSize;                          % örnekleme penceresi frekansı
               % ov=ws/ov_size;                                    % Pencere iç içe geçme frekansı
                [ExcelSure, ExcelTxt] = xlsread(strcat(current_path,'\EXCEL\',DosyaAdi,'.xlsx'));  % Her bir Excel dosyasındaki deney adları ve süre bilgileri (sn) olarak
             
                EEG=doEEGOku(app,dosya,ToplamEEGKanalNo,ToplamKanalAdedi,fktotal,fk,fo); % fk= edfdosyasındaki satır sayısı saniyede 2 adet , fo Örnekleme frekansı
               
                if app.SaveFiltredEEGCheckBox.Value==true            % Çalıştırmadan sadece Filtrelenmiş EEG datasını sakla
                    deneyAdi=app.ExperimentNameEditField.Value;
                    save(append(deneyAdi,'\FilterDatalar\','Filtered',string(DosyaAdi),'.mat'), "EEG");
                    return;
                end
                               
                [Sure,DeneyAdi] = doExcelDosyaOku(app,ExcelSure, ExcelTxt, GorDeneyNo, UygulamaSayisi); % excel dizinindeki Deney adları ve süreleri

                doExperiment(app,GorDeneyNo,UygulamaSayisi,DeneyAdi,Sure,DeneyBasSuresi,DeneySuresi, ...
                    fo,ws,ov,EEG,ToplamKanalAdi,SecilenKanalAdedi,filterName,ColorIndx, ...
                    ExcelTxt,SPyol,SPyolDusuk,SCyol,SCyolDusuk,SPCyol,SPCyolDusuk,BMyol,BMyolDusuk, ...
                    CwtFilter,deneyAdi,SecilenKanalNo,FiltreTipi,SecilenKanalAdi,SNRPath)
                  
                close all
            end
            app.Lamp.Color="green";
            app.RUNButton.BackgroundColor=[0.96 0.96 0.96];
        end

        % Value changed function: BandPassCheckBox
        function BandPassCheckBoxValueChanged(app, event)
            if app.BandPassCheckBox.Value==true
                app.F1PassFrequecyEditField.Enable=true;
                app.F1StopFrequecyEditField.Enable=true;
                app.F2PassFrequecyEditField.Enable=true;
                app.F2StopFrequecyEditField .Enable=true;
                app.BPPassGainEditField.Enable=true;
                app.BPStopGainEditField .Enable=true;
                app.EEG_BandsButtonGroup.Enable=true;
            else
                app.F1PassFrequecyEditField.Enable=false;
                app.F1StopFrequecyEditField.Enable=false;
                app.F2PassFrequecyEditField.Enable=false;
                app.F2StopFrequecyEditField .Enable=false;
                app.BPPassGainEditField.Enable=false;
                app.BPStopGainEditField .Enable=false;
                app.EEG_BandsButtonGroup.Enable=false;
            end
        end

        % Button pushed function: Inspect_MATButton
        function Inspect_MATButtonPushed(app, event)
            global ws; global ov;
            app.Lamp.Visible=true;
            app.Labelx.Text='MAT file is prosessing Please WAIT';
            app.Lamp.Color="red";
            fo=256;
            [file, path] = uigetfile('*.mat', 'Select a MAT file');
            if isequal(file, 0)
                disp('Dosya seçilmedi.');
            else
                fileStruct.filepath = fullfile(path, file);  % Store inside struct
                EEG = importdata(fileStruct.filepath);
                app.Label2.Text= fullfile(path, file);
                [x,y]=size(EEG);    % x Satır, y sutun

                if   (x==1)  % time, fft, scpectrogram, scolagram
                    L = length(EEG );
                    Ttime=(0:length(EEG)-1 ) /fo;
                    fa=figure; set(fa,'Name',file) ;
                    subplot(5,1,1)
                    plot(Ttime,EEG); xlabel('Time(sec)');ylabel('Power');title(' Filtered time signal')

                    subplot(5,1,2)
                    [pxx,f] = pspectrum(EEG,fo);
                    plot(f,pow2db(pxx));
                    grid on;

                    title("Single-Sided Amplitude Spectrum of Filtered Signal");xlabel("f (Hz)");yylabel("'Power/Frequency (dB/Hz)")

                    subplot(5,1,3) ;        % Spectrogram
                    nfft = 2^nextpow2(length( EEG));
                    filterName =app.SpectrogramFilterTypeListBox.Value;
                    switch   filterName
                        case 'f_barthannwin'
                            [s, f, t]=spectrogram(EEG,barthannwin(ws),ov,nfft,fo);
                        case 'f_bartlett'
                            [s, f, t]=spectrogram(EEG,bartlett(ws),ov,nfft,fo);
                        case 'f_chebwin'
                            [s, f, t]=spectrogram(EEG,chebwin(ws),ov,nfft,fo);
                        case 'f_hamming'
                            [ s, f, t]=spectrogram(dEEG,hamming(ws),ov,nfft,fo);
                        case 'f_hann'
                            [s, f, t]=spectrogram(EEG,hann(ws),ov,nfft,fo);
                        case 'f_kaiser'
                            [s, f, t]=spectrogram(EEG,kaiser(ws),ov,nfft,fo);
                        case 'f_taylorwin'
                            [s, f, t]=spectrogram(EEG,taylorwin(ws),ov,nfft,fo);
                    end

                    val = 10*log10(abs(s));
                    if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                        f1=app.F1PassFrequecyEditField.Value;
                        f2=app.F2PassFrequecyEditField.Value;
                        imagesc(t,f,val);
                        ylim([f1 f2]);
                    else
                        imagesc(t,f,val);
                    end

                    colormap(app.ColorListBox.Value); set(gca,"yscale","linear","YDir","normal");
                    xlabel('Time (sec)') ;ylabel('Frequency(Hz') ; title(' Spectrogram');colorbar;

                    subplot(5,1,4);  %% scolagaram
                    [minfreq,maxfreq] = cwtfreqbounds(L, fo);
                    fb = cwtfilterbank(SignalLength=L,  SamplingFrequency=fo, FrequencyLimits=[minfreq maxfreq]);
                    freqz(fb)

                    [cff, ~]=wt(fb,EEG);
                    cfs=10*log10(abs(cff));

                    if app.ShowOnlySelectedFrequencyCheckBox.Value==true
                        f1=app.F1PassFrequecyEditField.Value;
                        f2=app.F2PassFrequecyEditField.Value;
                        imagesc(t,f,cfs);
                        ylim([f1 f2]);
                    else
                        imagesc(t,f,cfs);
                    end
                    set(gca,"yscale","linear","YDir","normal");colormap(app.ColorListBox.Value)  ;
                    xlabel('Time (sec)');ylabel('Frequency(Hz)');title(' Scalogram');colorbar;
                    subplot(5,1,5);
                    pspectrum(EEG,fo,"spectrogram")
                    title("Pspectrom")
                    cc = clim;
                    xl = xlim;

                elseif (and((x>=1),(y==fo* app.Experiment_DurationEditField.Value )))  % stacplot, brainmap
                    t= linspace(1, y/(fo), y);
                    figure('Name',file);
                    aa=stackedplot(t,EEG');

                    aa.Position=[0.03,0.017,0.96,0.98];

                    C3=	[-90 0.5];C4=[90 0.5]; CZ=[90 0];F3=[-39 0.75]; F4=[39 0.75];F7=[-54 1];F8=[54 1];FZ=[0 0.5];FP1=[-15 1];FP2=[15 1];FPZ=[0 1];A1=[-90 1.05 ];
                    A2=[90 1.05];O1=[-162 1];O2=[162 1];OZ=[-180 1];P3=[-219 0.75]; P4=[219 0.75];T5=[-234 1];T6=[234 1];PZ=[-180 0.5];T3=[-90 1];T4=[90 1]; T1=[0  0]; T2=[0 0];

                    cap=([C3(2), C4(2),CZ(2),F3(2),F4(2),F7(2),F8(2),FZ(2),FP1(2),FP2(2),FPZ(2),A1(2),A2(2),O1(2),O2(2),OZ(2),P3(2),P4(2),T5(2),T6(2),PZ(2),T3(2),T4(2),T1(2),T2(2)]);
                    aci=([C3(1), C4(1),CZ(1),F3(1),F4(1),F7(1),F8(1),FZ(1),FP1(1),FP2(1),FPZ(1),A1(1),A2(1),O1(1),O2(1),OZ(1),P3(1),P4(1),T5(1),T6(1),PZ(1),T3(1),T4(1),T1(1),T2(1)]);
                    aci=deg2rad(aci+(pi/2));
                    eeg_intensity=mean(EEG');
                    xa= cap .* cos(aci);		% Kanal koordinatlarını x-y düzleminde hesapla
                    ya = cap .* sin(aci);
                    [xq, yq] = meshgrid(linspace(-1, 1, 100), linspace(-1, 1, 100)); % yoğunluk ölceği 0-100 arasında
                    vq = griddata(xa, ya, eeg_intensity, xq, yq, 'cubic');
                    theta_circle = linspace(0, 2*pi, 100); 	% Kafa sınırını çizmek için daire
                    circle_x = cos(theta_circle);
                    circle_y = sin(theta_circle);
                    figure('Color',[1 1 1] ,'Position',[68 63  560 420],'Name', file);   % Kafa haritasını çiz
                    ax = axes;
                    hold on;
                    P=contourf(xq, yq, vq, 20, 'LineColor', 'none'); % Yoğunluk renk haritası
                    set(gca, 'Color', 'none');
                    plot(circle_x, circle_y, 'k', 'LineWidth', 2); % Kafa sınırı
                    scatter(xa, ya, 50, eeg_intensity, 'filled', 'MarkerEdgeColor', 'k'); % Kanal noktaları
                    colorbar;colormap jet;axis equal;axis off;
                    hold off
                else    %stackplot
                    t= linspace(1, y/(fo), y);
                    figure('Name',file);
                    aa=stackedplot(t,EEG');
                    % aa.DisplayLabels ={'C3',  'C4', 'CZ', 'F3', 'F4', 'F7', 'F8', 'FZ', 'FP1', 'FP2', 'FPZ', 'A1'...
                    %    'A2', 'O1', 'O2', 'OZ', 'P3', 'P4', 'T5', 'T6', 'PZ', 'T3', 'T4', 'T1', 'T2'};
                    aa.Position=[0.03,0.017,0.96,0.98];
                end
            end
            app.Lamp.Color="green";
            app.Label2.Text='--------';
            app.Labelx.Text='--------';
        end

        % Value changed function: SelectPatientCheckBox
        function SelectPatientCheckBoxValueChanged(app, event)

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if  value =="C3"
                ChannelnelNo=1;
            elseif value =="C4"
                ChannelnelNo=2;
            elseif value =="CZ"
                ChannelnelNo=3;
            elseif value =="F3"
                ChannelnelNo=4;
            elseif value =="F4"
                ChannelnelNo=5;
            elseif value =="F7"
                ChannelnelNo=6;
            elseif value =="F8"
                ChannelnelNo=7;
            elseif value =="FZ"
                ChannelnelNo=8;
            elseif value =="FP1"
                ChannelnelNo=9;
            elseif value =="FP2"
                ChannelnelNo=10;
            elseif value =="FPZ"
                ChannelnelNo=11;
            elseif value =="A1"
                ChannelnelNo=12;
            elseif value =="A2"
                ChannelnelNo=13;
            elseif value =="01"
                ChannelnelNo=14;
            elseif value =="O2"
                ChannelnelNo=15;
            elseif value =="OZ"
                ChannelnelNo=16;
            elseif value =="P3"
                ChannelnelNo=17;
            elseif value =="P4"
                ChannelnelNo=18;
            elseif value =="T5"
                ChannelnelNo=19;
            elseif value =="T6"
                ChannelnelNo=20;
            elseif value =="PZ"
                ChannelnelNo=21;
            elseif value =="T3"
                ChannelnelNo=22;
            elseif value =="T4"
                ChannelnelNo=23;
            elseif value =="T1"
                ChannelnelNo=24;
            elseif value =="T2"
                ChannelnelNo=25;
            else
                ChannelnelNo=9;
            end
            app.Channel_NoEditField.Value=ChannelnelNo;
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            % value = app.SpeedSlider.Value;
            app.speedEditField.Value=app.SpeedSlider.Value;
        end

        % Value changed function: ArtifactFilter_EDFCheckBox
        function ArtifactFilter_EDFCheckBoxValueChanged(app, event)
            secildi = app.ArtifactFilter_EDFCheckBox.Value;
            if secildi
            app.Artifact_Filter_PerformanceCheckBox.Enable=true;
            else
                app.Artifact_Filter_PerformanceCheckBox.Enable=false;
                app.Artifact_Filter_PerformanceCheckBox.Value=false;
            end
        end

        % Value changed function: LineFilter_EDFCheckBox
        function LineFilter_EDFCheckBoxValueChanged(app, event)
           secildi  = app.LineFilter_EDFCheckBox.Value;
            if secildi
            app.Line_Filter_PerformanceCheckBox.Enable=true;
            else
                app.Line_Filter_PerformanceCheckBox.Enable=false;
                app.Line_Filter_PerformanceCheckBox.Value=false;
            end
            
        end

        % Value changed function: FP1CheckBox
        function FP1CheckBoxValueChanged(app, event)
           
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EEGUIFigure = uifigure('Visible', 'off');
            app.EEGUIFigure.Name = 'EEG';
            app.Toolbar = uitoolbar(app.EEGUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'brainels.gif');
            app.TabGroup = uitabgroup(app.EEGUIFigure);
            app.LineFilterDesignTab = uitab(app.TabGroup);
            app.LineFilterDesignTab.Title = 'LineFilterDesign';
            app.Notch_Q_factorEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.Notch_Q_factorEditFieldLabel.Tooltip = {'Notch Filtre Q katsayısı '};
            app.Notch_Q_factorEditFieldLabel.Text = 'Notch_Q_factor';
            app.Notch_Q_factorEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.Notch_Q_factorEditField.Enable = 'off';
            app.Notch_Q_factorEditField.Tooltip = {'Filtre Q (kalite) faktörü'};
            app.Notch_Q_factorEditField.Value = 35;
            app.NotchCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Tooltip = {'50 Hz filtre '};
            app.NotchCheckBox.Text = 'Notch';
            app.NotchCheckBox.Value = true;
            app.HighPassCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.HighPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighPassCheckBoxValueChanged, true);
            app.HighPassCheckBox.Tooltip = {'Yüksek geçirgen filtre DC sinyali yok etmek için '};
            app.HighPassCheckBox.Text = 'HighPass';
            app.BandPassCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.BandPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassCheckBoxValueChanged, true);
            app.BandPassCheckBox.Tooltip = {'Band geçiren filtre '};
            app.BandPassCheckBox.Text = 'BandPass';
            app.StopBandFrequencyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.StopBandFrequencyEditFieldLabel.Text = 'StopBandFrequency';
            app.StopBandFrequencyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.StopBandFrequencyEditField.Limits = [0 10];
            app.StopBandFrequencyEditField.Enable = 'off';
            app.StopBandFrequencyEditField.Tooltip = {'Yüksek Geçiren Filtre  durdurma freakansı'};
            app.StopBandFrequencyEditField.Value = 0.2;
            app.F1StopFrequecyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.F1StopFrequecyEditFieldLabel.Text = 'F1StopFrequecy';
            app.F1StopFrequecyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.F1StopFrequecyEditField.Enable = 'off';
            app.F1StopFrequecyEditField.Tooltip = {'Band Geçiren Filtre alt durdurma frekansı '};
            app.F1StopFrequecyEditField.Value = 7.9;
            app.F1PassFrequecyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.F1PassFrequecyEditFieldLabel.Text = 'F1PassFrequecy';
            app.F1PassFrequecyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.F1PassFrequecyEditField.Enable = 'off';
            app.F1PassFrequecyEditField.Tooltip = {'Band Geçiren Filtre alt geçirme frekansı '};
            app.F1PassFrequecyEditField.Value = 8;
            app.PassbandFrequencyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.PassbandFrequencyEditFieldLabel.Text = 'PassbandFrequency';
            app.PassbandFrequencyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.PassbandFrequencyEditField.Enable = 'off';
            app.PassbandFrequencyEditField.Tooltip = {'Yüksek Geçiren Filtre Geçirme freakansı'};
            app.PassbandFrequencyEditField.Value = 0.8;
            app.HPStopGainEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.HPStopGainEditFieldLabel.Text = 'HPStopGain';
            app.HPStopGainEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.HPStopGainEditField.Enable = 'off';
            app.HPStopGainEditField.Tooltip = {'Yüksek Geçiren Filtre durdurma kazancı - dB olarak'};
            app.HPStopGainEditField.Value = 60;
            app.HPPassGainEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.HPPassGainEditFieldLabel.Text = 'HPPassGain';
            app.HPPassGainEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.HPPassGainEditField.Enable = 'off';
            app.HPPassGainEditField.Tooltip = {'Filtre Geçirme kazancı - dB olarak'};
            app.HPPassGainEditField.Value = 1;
            app.BPStopGainEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.BPStopGainEditFieldLabel.Text = 'BPStopGain';
            app.BPStopGainEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.BPStopGainEditField.Enable = 'off';
            app.BPStopGainEditField.Tooltip = {'Band  Geçiren Filtre durdurma kazancı - dB olarak'};
            app.BPStopGainEditField.Value = 3;
            app.BPPassGainEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.BPPassGainEditFieldLabel.Text = 'BPPassGain';
            app.BPPassGainEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.BPPassGainEditField.Enable = 'off';
            app.BPPassGainEditField.Tooltip = {'Band  Geçiren Filtre geçirmekazancı - dB olarak'};
            app.BPPassGainEditField.Value = 1;
            app.F2PassFrequecyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.F2PassFrequecyEditFieldLabel.Text = 'F2PassFrequecy';
            app.F2PassFrequecyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.F2PassFrequecyEditField.Enable = 'off';
            app.F2PassFrequecyEditField.Tooltip = {'Band Geçiren Filtre üst geçirme frekansı '};
            app.F2PassFrequecyEditField.Value = 30;
            app.F2StopFrequecyEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.F2StopFrequecyEditFieldLabel.Text = 'F2StopFrequecy';
            app.F2StopFrequecyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.F2StopFrequecyEditField.Enable = 'off';
            app.F2StopFrequecyEditField.Tooltip = {'Band Geçiren Filtre üst durdurma frekansı '};
            app.F2StopFrequecyEditField.Value = 30.1;
            app.LineFilterButton = uibutton(app.LineFilterDesignTab, 'push');
            app.LineFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LineFilterButtonPushed, true);
            app.LineFilterButton.Tooltip = {'Seçilen filtrenin uygun olup olmadığını görmek için filtre karaktreistiğini ve sinyali göster'};
            app.LineFilterButton.Text = 'LineFilter';
            app.NotchFrequencyLabel = uilabel(app.LineFilterDesignTab);
            app.NotchFrequencyLabel.Text = 'NotchFrequency';
            app.NotchFrequencyEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.NotchFrequencyEditField.Enable = 'off';
            app.NotchFrequencyEditField.Tooltip = {'Şebeke frekansı'};
            app.NotchFrequencyEditField.Value = 50;
            app.SaveChannelFilterOutCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.SaveChannelFilterOutCheckBox.Tooltip = {'Seçilen Filtre Tipine göre istenilen kanala ait EEG datasını FilterDatalar dizini altında saklar'};
            app.SaveChannelFilterOutCheckBox.Text = 'SaveChannelFilterOut';
            app.EEG_BandsButtonGroup = uibuttongroup(app.LineFilterDesignTab);
            app.EEG_BandsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EEG_BandsButtonGroupSelectionChanged, true);
            app.EEG_BandsButtonGroup.Tooltip = {'Seçilen EEG Band değerine göre Band geçiren Filtre değerleri ayarlanır'};
            app.EEG_BandsButtonGroup.Enable = 'off';
            app.EEG_BandsButtonGroup.Title = 'EEG_Bands';
            app.DeltaButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.DeltaButton.Text = 'Delta';
            app.ThetaButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.ThetaButton.Text = 'Theta';
            app.AlphaButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.AlphaButton.Text = 'Alpha';
            app.BetaButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.BetaButton.Text = 'Beta';
            app.GamaButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.GamaButton.Text = 'Gama';
            app.UserDefinedButton = uiradiobutton(app.EEG_BandsButtonGroup);
            app.UserDefinedButton.Text = 'UserDefined';
            app.UserDefinedButton.Value = true;
            app.ShowOnlySelectedFrequencyCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.ShowOnlySelectedFrequencyCheckBox.Tooltip = {'Grafikleri BandPass filtre üzerinden secilen EEG bandları veya BandPass filtre aralığında çizer.'};
            app.ShowOnlySelectedFrequencyCheckBox.Text = 'ShowOnlySelectedFrequency';
            app.ShowOnlySelectedFrequencyCheckBox.Value = true;
            app.ListBox = uilistbox(app.LineFilterDesignTab);
            app.ListBox.Items = {'C3', 'C4', 'CZ', 'F3', 'F4', 'F7', 'F8', 'FZ', 'FP1', 'FP2', 'FPZ', 'A1', 'A2', 'O1', 'O2', 'OZ', 'P3', 'P4', 'T5', 'T6', 'PZ', 'T3', 'T4', 'T1', 'T2'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'FP1';
            app.Channel_NoEditFieldLabel = uilabel(app.LineFilterDesignTab);
            app.Channel_NoEditFieldLabel.Text = 'Channel_No';
            app.Channel_NoEditField = uieditfield(app.LineFilterDesignTab, 'numeric');
            app.Channel_NoEditField.Value = 9;
            app.ChannelNameLabel = uilabel(app.LineFilterDesignTab);
            app.ChannelNameLabel.Text = 'Channel Name';
            app.ShowSNRCheckBox = uicheckbox(app.LineFilterDesignTab);
            app.ShowSNRCheckBox.Text = 'ShowSNR';
            app.ExperimentTab = uitab(app.TabGroup);
            app.ExperimentTab.Title = 'Experiment ';
            app.EEGParametreleriPanel = uipanel(app.ExperimentTab);
            app.EEGParametreleriPanel.Title = 'EEG Parametreleri';
            app.TotalRecordLabel = uilabel(app.EEGParametreleriPanel);
            app.TotalRecordLabel.Text = 'Total Record';
            app.TotalRecordTimesecLabel = uilabel(app.EEGParametreleriPanel);
            app.TotalRecordTimesecLabel.Text = 'Total Record Time(sec)';
            app.F_RecordLabel = uilabel(app.EEGParametreleriPanel);
            app.F_RecordLabel.Text = 'F_Record';
            app.F_TimeLabel = uilabel(app.EEGParametreleriPanel);
            app.F_TimeLabel.Text = 'F_Time';
            app.PatientNameLabel = uilabel(app.EEGParametreleriPanel);
            app.PatientNameLabel.Tooltip = {'Seçilen hasta adı'};
            app.PatientNameLabel.Text = 'Patient Name';
            app.F_PatientNameLabel = uilabel(app.EEGParametreleriPanel);
            app.F_PatientNameLabel.Text = 'F_PatientName';
            app.SamplingFrequencyHzLabel = uilabel(app.EEGParametreleriPanel);
            app.SamplingFrequencyHzLabel.Tooltip = {'Örnekleme Frekansı'};
            app.SamplingFrequencyHzLabel.Text = 'Sampling Frequency (Hz)';
            app.F_SamplingLabel = uilabel(app.EEGParametreleriPanel);
            app.F_SamplingLabel.Text = 'F_Sampling';
            app.Idle_TimeEditFieldLabel = uilabel(app.EEGParametreleriPanel);
            app.Idle_TimeEditFieldLabel.Text = 'Idle_Time';
            app.Idle_TimeEditField = uieditfield(app.EEGParametreleriPanel, 'numeric');
            app.Idle_TimeEditField.RoundFractionalValues = 'on';
            app.Idle_TimeEditField.Tooltip = {'Deneylerin ilk başlangıç süresi saniye cinsinden ayarlanır'};
            app.Idle_TimeEditField.Value = 3;
            app.Experiment_DurationEditFieldLabel = uilabel(app.EEGParametreleriPanel);
            app.Experiment_DurationEditFieldLabel.Text = 'Experiment_Duration';
            app.Experiment_DurationEditField = uieditfield(app.EEGParametreleriPanel, 'numeric');
            app.Experiment_DurationEditField.RoundFractionalValues = 'on';
            app.Experiment_DurationEditField.Tooltip = {'Deney süresi sn olarak'};
            app.Experiment_DurationEditField.Value = 10;
            app.Total_ExperimentEditFieldLabel = uilabel(app.EEGParametreleriPanel);
            app.Total_ExperimentEditFieldLabel.Text = 'Total_Experiment';
            app.Total_ExperimentEditField = uieditfield(app.EEGParametreleriPanel, 'numeric');
            app.Total_ExperimentEditField.RoundFractionalValues = 'on';
            app.Total_ExperimentEditField.Tooltip = {'Her bir EXCEL dosyasındaki maksimum deney sayısı'};
            app.Total_ExperimentEditField.Value = 6;
            app.Movement_NumberEditFieldLabel = uilabel(app.EEGParametreleriPanel);
            app.Movement_NumberEditFieldLabel.Text = 'Movement_Number';
            app.Movement_NumberEditField = uieditfield(app.EEGParametreleriPanel, 'numeric');
            app.Movement_NumberEditField.RoundFractionalValues = 'on';
            app.Movement_NumberEditField.Tooltip = {'Her bir deneydeki toplam hareket sayısı'};
            app.Movement_NumberEditField.Value = 16;
            app.SelectPatientCheckBox = uicheckbox(app.ExperimentTab);
            app.SelectPatientCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectPatientCheckBoxValueChanged, true);
            app.SelectPatientCheckBox.Tooltip = {'Tek bir hasta için işaretle'};
            app.SelectPatientCheckBox.Text = 'SelectPatient';
            app.RUNButton = uibutton(app.ExperimentTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.SpectrogramCheckBox = uicheckbox(app.ExperimentTab);
            app.SpectrogramCheckBox.Tooltip = {'Spektrogram sinyali için işaretle'};
            app.SpectrogramCheckBox.Text = 'Spectrogram';
            app.SpectrogramCheckBox.Value = true;
            app.ScalogramCWTCheckBox = uicheckbox(app.ExperimentTab);
            app.ScalogramCWTCheckBox.Tooltip = {'Scolagrm sinyali için işaretle'};
            app.ScalogramCWTCheckBox.Text = 'Scalogram(CWT)';
            app.Topographic_mapCheckBox = uicheckbox(app.ExperimentTab);
            app.Topographic_mapCheckBox.Tooltip = {'Beyin haritası için işaretle'};
            app.Topographic_mapCheckBox.Text = 'Topographic_ map';
            app.SaveFiltredEEGCheckBox = uicheckbox(app.ExperimentTab);
            app.SaveFiltredEEGCheckBox.Tooltip = {'Programı çalıştırmaz sadece seçilen filtre tipi için tüm EEG datasının secilen filtre tipine göre filtreler ve sonucu saklar'};
            app.SaveFiltredEEGCheckBox.Text = 'SaveFiltredEEG';
            app.SaveMatCheckBox = uicheckbox(app.ExperimentTab);
            app.SaveMatCheckBox.Tooltip = {'Her bir deney için .MATdosyasını saklar.'};
            app.SaveMatCheckBox.Text = 'SaveMat';
            app.Save_Channel_MatCheckBox = uicheckbox(app.ExperimentTab);
            app.Save_Channel_MatCheckBox.Text = 'Save_Channel_Mat';
            app.ArtifactFilter_EDFCheckBox = uicheckbox(app.ExperimentTab);
            app.ArtifactFilter_EDFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArtifactFilter_EDFCheckBoxValueChanged, true);
            app.ArtifactFilter_EDFCheckBox.Text = 'ArtifactFilter_EDF';
            app.PSpectrumCheckBox = uicheckbox(app.ExperimentTab);
            app.PSpectrumCheckBox.Text = 'PSpectrum';
            app.LineFilter_EDFCheckBox = uicheckbox(app.ExperimentTab);
            app.LineFilter_EDFCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineFilter_EDFCheckBoxValueChanged, true);
            app.LineFilter_EDFCheckBox.Text = 'LineFilter_EDF';
            app.Artifact_Filter_PerformanceCheckBox = uicheckbox(app.ExperimentTab);
            app.Artifact_Filter_PerformanceCheckBox.Enable = 'off';
            app.Artifact_Filter_PerformanceCheckBox.Text = 'Artifact_Filter_Performance';
            app.Line_Filter_PerformanceCheckBox = uicheckbox(app.ExperimentTab);
            app.Line_Filter_PerformanceCheckBox.Enable = 'off';
            app.Line_Filter_PerformanceCheckBox.Text = 'Line_Filter_Performance';
            app.Inspect_FileTab = uitab(app.TabGroup);
            app.Inspect_FileTab.Title = 'Inspect_File';
            app.Inspect_EDFButton = uibutton(app.Inspect_FileTab, 'push');
            app.Inspect_EDFButton.ButtonPushedFcn = createCallbackFcn(app, @Inspect_EDFButtonPushed, true);
            app.Inspect_EDFButton.Tooltip = {'EEG data incele'};
            app.Inspect_EDFButton.Text = 'Inspect_EDF';
            app.Inspect_MATButton = uibutton(app.Inspect_FileTab, 'push');
            app.Inspect_MATButton.ButtonPushedFcn = createCallbackFcn(app, @Inspect_MATButtonPushed, true);
            app.Inspect_MATButton.Tooltip = {'.mat dosyalarını inceler'; '-Tek kanal için Time&FFT&Spectrugram& Scolagram sinyalleri, '; '- Çok kanal, sadece tek bir hareket için bütün EEG kanallları ve BrainMap grafiği'; '- Çok kanal bütün haraketler için  bütün EEG kanallları gösterir.'};
            app.Inspect_MATButton.Text = 'Inspect_MAT';
            app.SaveRawEEGCheckBox = uicheckbox(app.Inspect_FileTab);
            app.SaveRawEEGCheckBox.Tooltip = {'Raw EDF datasını /FilterDatalar dizini altında MAT halinde saklar'};
            app.SaveRawEEGCheckBox.Text = 'SaveRawEEG';
            app.ArtifacFilterCheckBox = uicheckbox(app.Inspect_FileTab);
            app.ArtifacFilterCheckBox.Tooltip = {'Artifact Filtre uygulanır'};
            app.ArtifacFilterCheckBox.Text = 'ArtifacFilter';
            app.ArtifacFilterCheckBox.Value = true;
            app.Show_Only_Selected_ChannelsCheckBox = uicheckbox(app.Inspect_FileTab);
            app.Show_Only_Selected_ChannelsCheckBox.Tooltip = {'Seçilmezse Bütün kanallar gösterilir'};
            app.Show_Only_Selected_ChannelsCheckBox.Text = 'Show_Only_Selected_Channels';
            app.ExperimentNoListBoxLabel = uilabel(app.Inspect_FileTab);
            app.ExperimentNoListBoxLabel.Text = 'ExperimentNo';
            app.ExperimentNoListBox = uilistbox(app.Inspect_FileTab);
            app.ExperimentNoListBox.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.ExperimentNoListBox.Value = '1';
            app.LineFilterCheckBox = uicheckbox(app.Inspect_FileTab);
            app.LineFilterCheckBox.Text = 'LineFilter';
            app.LineFilterCheckBox.Value = true;
            app.MakeVideoCheckBox = uicheckbox(app.Inspect_FileTab);
            app.MakeVideoCheckBox.Text = 'MakeVideo';
            app.SpeedSliderLabel = uilabel(app.Inspect_FileTab);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Inspect_FileTab);
            app.SpeedSlider.Limits = [0 10];
            app.SpeedSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.MinorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6 6.25 6.5 6.75 7 7.25 7.5 7.75 8 8.25 8.5 8.75 9 9.25 9.5 9.75 10];
            app.SpeedSlider.Value = 10;
            app.ShowChannel_FrquencPowerCheckBox = uicheckbox(app.Inspect_FileTab);
            app.ShowChannel_FrquencPowerCheckBox.Text = 'ShowChannel_Frquenc&Power';
            app.speedEditFieldLabel = uilabel(app.Inspect_FileTab);
            app.speedEditFieldLabel.Text = 'speed';
            app.speedEditField = uieditfield(app.Inspect_FileTab, 'numeric');
            app.milisecondLabel = uilabel(app.Inspect_FileTab);
            app.milisecondLabel.Text = 'milisecond';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.Panel = uipanel(app.ParameterTab);
            app.Panel.Title = 'Panel';
            app.OverlapEditFieldLabel = uilabel(app.Panel);
            app.OverlapEditFieldLabel.Text = 'Overlap';
            app.OverlapEditField = uieditfield(app.Panel, 'numeric');
            app.OverlapEditField.Limits = [1 1000];
            app.OverlapEditField.Tooltip = {'Spektrogram Örtüşme boyutu'};
            app.OverlapEditField.Value = 256;
            app.Windows_SizeEditFieldLabel = uilabel(app.Panel);
            app.Windows_SizeEditFieldLabel.Text = 'Windows_Size';
            app.Windows_SizeEditField = uieditfield(app.Panel, 'numeric');
            app.Windows_SizeEditField.Limits = [0 1024];
            app.Windows_SizeEditField.Tooltip = {'Spektrogram Windows boyutu'};
            app.Windows_SizeEditField.Value = 512;
            app.SpectrogramFilterTypeListBoxLabel = uilabel(app.Panel);
            app.SpectrogramFilterTypeListBoxLabel.Text = 'SpectrogramFilterType';
            app.SpectrogramFilterTypeListBox = uilistbox(app.Panel);
            app.SpectrogramFilterTypeListBox.Items = {'f_NoFilter', 'f_barthannwin', 'f_bartlett', 'f_chebwin', 'f_hamming', 'f_hann', 'f_kaiser', 'f_taylorwin'};
            app.SpectrogramFilterTypeListBox.Tooltip = {'Spectrogram filtresi'};
            app.SpectrogramFilterTypeListBox.Value = 'f_hann';
            app.ColorListBox = uilistbox(app.Panel);
            app.ColorListBox.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'jet', 'lines', 'colorcube', 'prism'};
            app.ColorListBox.Tooltip = {'Spectrogram ve Sclogaram için renk değeri'};
            app.ColorListBox.Value = 'parula';
            app.ColorLabel = uilabel(app.Panel);
            app.ColorLabel.Text = 'Color';
            app.ExperimentNameEditFieldLabel = uilabel(app.Panel);
            app.ExperimentNameEditFieldLabel.Text = 'ExperimentName';
            app.ExperimentNameEditField = uieditfield(app.Panel, 'text');
            app.ExperimentNameEditField.Value = 'Experiment1';
            app.WaveletTypeListBoxLabel = uilabel(app.Panel);
            app.WaveletTypeListBoxLabel.Text = 'WaveletType';
            app.WaveletTypeListBox = uilistbox(app.Panel);
            app.WaveletTypeListBox.Items = {'morse', 'amor', 'bump'};
            app.WaveletTypeListBox.Value = 'amor';
            app.ElectrodesTab = uitab(app.TabGroup);
            app.ElectrodesTab.Title = 'Electrodes';
            app.C3CheckBox = uicheckbox(app.ElectrodesTab);
            app.C3CheckBox.Text = 'C3';
            app.CZCheckBox = uicheckbox(app.ElectrodesTab);
            app.CZCheckBox.Text = 'CZ';
            app.CZCheckBox.Value = true;
            app.F3CheckBox = uicheckbox(app.ElectrodesTab);
            app.F3CheckBox.Text = 'F3';
            app.F4CheckBox = uicheckbox(app.ElectrodesTab);
            app.F4CheckBox.Text = 'F4';
            app.F7CheckBox = uicheckbox(app.ElectrodesTab);
            app.F7CheckBox.Text = 'F7';
            app.F8CheckBox = uicheckbox(app.ElectrodesTab);
            app.F8CheckBox.Text = 'F8';
            app.FZCheckBox = uicheckbox(app.ElectrodesTab);
            app.FZCheckBox.Text = 'FZ';
            app.FP1CheckBox = uicheckbox(app.ElectrodesTab);
            app.FP1CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP1CheckBoxValueChanged, true);
            app.FP1CheckBox.Text = 'FP1';
            app.C4CheckBox = uicheckbox(app.ElectrodesTab);
            app.C4CheckBox.Text = 'C4';
            app.C4CheckBox.Value = true;
            app.Image = uiimage(app.ElectrodesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Adsız.png');
            app.FP2CheckBox = uicheckbox(app.ElectrodesTab);
            app.FP2CheckBox.Text = 'FP2';
            app.T4CheckBox = uicheckbox(app.ElectrodesTab);
            app.T4CheckBox.Text = 'T4';
            app.A2CheckBox = uicheckbox(app.ElectrodesTab);
            app.A2CheckBox.Text = 'A2';
            app.T3CheckBox = uicheckbox(app.ElectrodesTab);
            app.T3CheckBox.Text = 'T3';
            app.A1CheckBox = uicheckbox(app.ElectrodesTab);
            app.A1CheckBox.Text = 'A1';
            app.PZCheckBox = uicheckbox(app.ElectrodesTab);
            app.PZCheckBox.Text = 'PZ';
            app.P4CheckBox = uicheckbox(app.ElectrodesTab);
            app.P4CheckBox.Text = 'P4';
            app.P4CheckBox.Value = true;
            app.P3CheckBox = uicheckbox(app.ElectrodesTab);
            app.P3CheckBox.Text = 'P3';
            app.T5CheckBox = uicheckbox(app.ElectrodesTab);
            app.T5CheckBox.Text = 'T5';
            app.T6CheckBox = uicheckbox(app.ElectrodesTab);
            app.T6CheckBox.Text = 'T6';
            app.O1CheckBox = uicheckbox(app.ElectrodesTab);
            app.O1CheckBox.Text = 'O1';
            app.O2CheckBox = uicheckbox(app.ElectrodesTab);
            app.O2CheckBox.Text = 'O2';
            app.OZCheckBox = uicheckbox(app.ElectrodesTab);
            app.OZCheckBox.Text = 'OZ';
            app.T1CheckBox = uicheckbox(app.ElectrodesTab);
            app.T1CheckBox.Text = 'T1';
            app.T2CheckBox = uicheckbox(app.ElectrodesTab);
            app.T2CheckBox.Text = 'T2';
            app.FPZCheckBox = uicheckbox(app.ElectrodesTab);
            app.FPZCheckBox.Text = 'FPZ';
            app.ArtifactFilterReferenceListBoxLabel = uilabel(app.ElectrodesTab);
            app.ArtifactFilterReferenceListBoxLabel.Text = 'ArtifactFilterReference';
            app.ArtifactFilterReferenceListBox = uilistbox(app.ElectrodesTab);
            app.ArtifactFilterReferenceListBox.Items = {'C3', 'C4', 'CZ', 'F3', 'F4', 'F7', 'F8', 'FZ', 'FP1', 'FP2', 'FPZ', 'A1', 'A2', 'O1', 'O2', 'OZ', 'P3', 'P4', 'T5', 'T6', 'PZ', 'T3', 'T4', 'T1', 'T2'};
            app.ArtifactFilterReferenceListBox.Value = 'FP1';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.TextArea = uitextarea(app.HelpTab);
            app.TextArea.Value = {'I- Inspect_File: Plots all EEG channel data from the selected file within the EDF directory. (Default: 25 channels)'; 'X SaveRawEEG: When selected, saves all EEG data from the desired EDF file as a Raw_xxx.mat file in the /FilterDatalar directory. '; ''; 'X InspectMat: When selected, handles different .mat files as follows:'; '- If "Raw_xxx.mat" is selected, it plots all EEG data. '; '- If "Filterxxxx.mat" is selected, it plots "time, FFT, Spectrogram, Scalogram, and [Spectrogram]" graphs.'; ''; 'II- FilterDesign: Creates a filter with desired specifications. Multiple filter types can be selected.'};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
            app.supportTab = uitab(app.TabGroup);
            app.supportTab.Title = 'support';
            app.Hyperlink2 = uihyperlink(app.supportTab);
            app.Hyperlink2.URL = 'https://avesis.marmara.edu.tr/aycaak';
            app.Hyperlink2.Text = 'Ayça Ak';
            app.Hyperlink3 = uihyperlink(app.supportTab);
            app.Hyperlink3.URL = 'https://avesis.marmara.edu.tr/vtopuz';
            app.Hyperlink3.Text = 'Vedat Topuz';
            app.PleasereportanycommentsorerrorsLabel = uilabel(app.supportTab);
            app.PleasereportanycommentsorerrorsLabel.Text = 'Please report any comments or errors.';
            app.Lamp = uilamp(app.EEGUIFigure);
            app.Lamp.Visible = 'off';
            app.Labelx = uilabel(app.EEGUIFigure);
            app.Labelx.Text = 'Labelx';
            app.STREEGLabel = uilabel(app.EEGUIFigure);
            app.STREEGLabel.Text = 'STREEG';
            app.Label2 = uilabel(app.EEGUIFigure);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.EEGUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STREEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGUIFigure)
        end
    end
end